classdef AnsIV_UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UI                   matlab.ui.Figure
        AnsIVs_AddIV_PN      matlab.ui.container.Panel
        DateEditFieldLabel   matlab.ui.control.Label
        AnsIVs_AddDate_EF    matlab.ui.control.EditField
        TimeEditFieldLabel   matlab.ui.control.Label
        AnsIVs_AddTime_EF    matlab.ui.control.EditField
        AnsIVs_AddIV_BT      matlab.ui.control.Button
        AnsIVs_AddIV_LP      matlab.ui.control.Lamp
        AnsIVs_IVL_Table     matlab.ui.control.Table
        AnsIVs_TBL_Clear_BT  matlab.ui.control.Button
        AnsIVs_path_fst_EF   matlab.ui.control.EditField
        AnsIVs_Save_BT       matlab.ui.control.Button
        AnsIVs_ext_RG        matlab.ui.container.ButtonGroup
        mat_BT               matlab.ui.control.RadioButton
        fig_BT               matlab.ui.control.RadioButton
        AnsIVs_Save_LP       matlab.ui.control.Lamp
        PathEditFieldLabel   matlab.ui.control.Label
        AnsIVs_path_snd_EF   matlab.ui.control.EditField
        X_Ax_BG              matlab.ui.container.ButtonGroup
        X_Ax_T_B             matlab.ui.control.ToggleButton
        X_Ax_G_B             matlab.ui.control.ToggleButton
        Y_Ax_BG              matlab.ui.container.ButtonGroup
        Y_Ax_Ic_B            matlab.ui.control.ToggleButton
        Y_Ax_Irt_B           matlab.ui.control.ToggleButton
        Y_Ax_RES_B           matlab.ui.control.ToggleButton
        Y_Az_IrtIc_B         matlab.ui.control.ToggleButton
        AnsIVs_plot_BT       matlab.ui.control.Button
        AnsIVs_plot_LP       matlab.ui.control.Lamp
        Mass_Load_BT         matlab.ui.control.Button
    end


    properties (Access = public)
        appMF; % Description
    end
    properties (Access = public)
        i = 1; % Count for data entries
        usf;
        anx; % Data for X axes
        any; % Data for Y axes
        %% Analysis
        fnd; % Path to data files
        lda; % Loaded data array
        T; % Loaded temperature array
        G; % Loaded gate voltage array
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, gui)
            app.appMF = gui;
            app.AnsIVs_path_fst_EF.Value = [app.appMF.sf,'Results/'];
            tech.amf_pos.bot_lft(app);
            tech.state.loadState(app);
        end

        % Button pushed function: AnsIVs_AddIV_BT
        function AnsIVs_AddIV_BTButtonPushed(app, event)
            try
                if (app.i == 1)||((~strcmp([app.fnd{:,1}],app.AnsIVs_AddDate_EF.Value))||(~strcmp([app.fnd{:,2}],app.AnsIVs_AddTime_EF.Value)))
                    app.fnd{app.i,1} = app.i;
                    app.fnd{app.i,2} = app.AnsIVs_AddDate_EF.Value;
                    app.fnd{app.i,3} = app.AnsIVs_AddTime_EF.Value;
                    lf = [app.appMF.sf,app.fnd{app.i,2},'/IV_',app.fnd{app.i,3},'.mat'];
                    app.lda{app.i} = load(lf,'data');
                    app.T{app.i} = load(lf,'T1');
                    app.G{app.i} = load(lf,'gv');
                    if length(app.G{app.i}.gv) > 1
                        app.X_Ax_T_B.Value = 0;
                        app.X_Ax_T_B.Enable = 'off';
                    end
                    app.AnsIVs_IVL_Table.Data = app.fnd;
                    app.i = app.i + 1;
                end
                %fprintf('%d\n',app.i);
                if app.i > 2
                    app.X_Ax_G_B.Enable = 'off';
                    app.X_Ax_T_B.Value = 1;
                end
            catch ME
                app.appMF.err(ME);
            end
        end

        % Button pushed function: AnsIVs_TBL_Clear_BT
        function AnsIVs_TBL_Clear_BTButtonPushed(app, event)
            cla(app.appMF.W_Ax)
            app.AnsIVs_IVL_Table.Data = [];
            app.i = 1;
            app.fnd = [];
            app.lda = [];
            app.T = [];
            app.G = [];
            %app.anLBpl.Items = {};
            app.X_Ax_G_B.Enable = 'on';
            app.X_Ax_T_B.Enable = 'on';
            app.X_Ax_G_B.Value = 0;
            app.X_Ax_T_B.Value = 0;
        end

        % Button pushed function: AnsIVs_plot_BT
        function AnsIVs_plot_BTButtonPushed(app, event)
            cla(app.appMF.W_Ax);
            la = length(app.lda);
            sx = app.X_Ax_BG.SelectedObject.Text;
            switch sx
                case 'T'
                    app.anx(1) = app.T{1}.T1;
                    for j = 2:la
                        app.anx(j) = app.T{j}.T1;
                    end
                case 'G'
                    app.anx = app.G{1}.gv;
            end
            sy = app.Y_Ax_BG.SelectedObject.Text;
            switch sy
                case 'Ic'
                    switch la
                        case 1
                            app.any = app.lda{1}.data.Isw;
                        otherwise
                            app.any(1) = app.lda{1}.data.Isw;
                            for j = 2:la
                                app.any(j) = app.lda{j}.data.Isw;
                            end    
                    end
                case 'Irt'
                    switch la
                        case 1
                            app.any = app.lda{1}.data.Irt;
                        otherwise
                            app.any(1) = app.lda{1}.data.Irt;
                            for j = 2:la
                                app.any(j) = app.lda{j}.data.Irt;
                            end
                    end
                case 'RES'
                    switch la
                        case 1
                            app.any = app.lda{1}.data.res;
                        otherwise
                            app.any(1) = app.lda{1}.data.res;
                            for j = 2:la
                                app.any(j) = app.lda{j}.data.res;
                            end
                    end
                case 'IrtIc'
                    switch la
                        case 1
                            app.any = app.lda{1}.data.Irt;
                            anz = app.lda{1}.data.Isw;
                        otherwise
                            app.any(1) = app.lda{1}.data.Irt;
                            for j = 2:la
                                app.any(j) = app.lda{j}.data.Irt;
                                anz(j) = app.lda{j}.data.Isw;
                                if anz(j) == 0
                                    disp(j);
                                end
                            end
                    end
                    plot(app.appMF.W_Ax,app.anx(:),anz,'b--o');
            end
%             disp('X')
%             disp(app.anx)
%             disp('Y');
%             disp(app.any)
            plot(app.appMF.W_Ax,app.anx(:),app.any(:),'r--o');
            %xlim([0])
            %ylim([0 ])
            hold(app.appMF.W_Ax,'on');
            title(app.appMF.W_Ax,[sy,'(',sx,')']);
            xlabel(app.appMF.W_Ax,sx);
            ylabel(app.appMF.W_Ax,sy);
%             if la > 1
%                 for j = 1:la
%                     text(app.appMF.W_Ax,app.anx(j),app.any(j),num2str(j),'VerticalAlignment','bottom','HorizontalAlignment','center');
%                     t{j} = num2str(j);
%                 end
%                 %app.anLBpl.Items = t;
%             end
            app.AnsIVs_Save_BT.Enable = 'on';
            %app.anLBpl.Enable = 'on';
            %app.anPlsp.Enable = 'on';
            %app.anCHPpl.Enable = 'on';
        end

        % Button pushed function: AnsIVs_Save_BT
        function AnsIVs_Save_BTButtonPushed(app, event)
            lbx = app.X_Ax_BG.SelectedObject.Text;
            lby = app.Y_Ax_BG.SelectedObject.Text;
            data = struct(lbx,app.anx,lby,app.any);
            mkdir([app.appMF.sf,'Results/']);
            switch app.AnsIVs_ext_RG.SelectedObject.Text
                case '.mat'
                    sf = [app.AnsIVs_path_fst_EF.Value,app.AnsIVs_path_snd_EF.Value,'.mat'];
                    save(sf,'data','sn');
                case '.fig+.jpg'
                    sf = [app.AnsIVs_path_fst_EF.Value,app.AnsIVs_path_snd_EF.Value];
                    f = figure;
                    movegui(f,'northeast');
                    %set(f,'NumberTitle','off','Name','IV');
%                     ax = axes(f);
%                     title(ax,[app.appMF.sn,' ',lby,'(',lbx,')']);
%                     xlabel(ax,lbx);
%                     ylabel(ax,lby);
%                     hold(ax,'on');
%                     plot(ax,app.anx,app.any,'o');
                    copyobj(app.UI,f);
                    savefig(f,sf);
                    saveas(f,sf,'jpeg');
            end
        end

        % Close request function: UI
        function UICloseRequest(app, event)
            app.appMF.AnsIVsMenu.Enable = 'on';
            tech.state.saveState(app);
            delete(app);  
        end

        % Button pushed function: Mass_Load_BT
        function Mass_Load_BTButtonPushed(app, event)
            s = strings;
            d = app.AnsIVs_AddDate_EF.Value;
            pa = [app.appMF.sf,d];
            li2 = dir(pa);
            li2 = rmfield(li2,{'date';'bytes';'isdir';'datenum';'folder'});
            l3 = length(li2);
            k = 1;
            for j = 1:l3-2
                c = textscan(li2(j+2).name,'%s','Delimiter','.');
            	v = c{1};
                c = textscan(v{1},'%s','Delimiter','_');
                m{k} = c{1};
                q = length(m{k});
                if (k > 1)&&(q==2)
                    if strcmp(m{k}{2},m{k-1}{2})
                        continue
                    end
                end
                if strcmp(m{k}{1},'IV')
                    s(k)= [v{1},'.mat'];
                end
                if q == 2
                    k = k + 1;
                end
            end
                for n = 1:length(s)
                    x = s(n);
                    lf = strcat(pa,'/',x);
                    disp(lf);
                    app.fnd{n,1} = n;
                    app.fnd{n,2} = d;
                    app.fnd{n,3} = m{n}{2};
                    app.lda{n} = load(lf,'data');
                    app.T{n} = load(lf,'T1');
                end
                app.AnsIVs_IVL_Table.Data = app.fnd;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UI = uifigure;
            app.UI.Name = 'Analyze IVs';
            app.UI.CloseRequestFcn = createCallbackFcn(app, @UICloseRequest, true);
            app.AnsIVs_AddIV_PN = uipanel(app.UI);
            app.AnsIVs_AddIV_PN.Title = 'Add IV';
            app.DateEditFieldLabel = uilabel(app.AnsIVs_AddIV_PN);
            app.DateEditFieldLabel.Text = 'Date';
            app.AnsIVs_AddDate_EF = uieditfield(app.AnsIVs_AddIV_PN, 'text');
            app.TimeEditFieldLabel = uilabel(app.AnsIVs_AddIV_PN);
            app.TimeEditFieldLabel.Text = 'Time';
            app.AnsIVs_AddTime_EF = uieditfield(app.AnsIVs_AddIV_PN, 'text');
            app.AnsIVs_AddIV_BT = uibutton(app.AnsIVs_AddIV_PN, 'push');
            app.AnsIVs_AddIV_BT.ButtonPushedFcn = createCallbackFcn(app, @AnsIVs_AddIV_BTButtonPushed, true);
            app.AnsIVs_AddIV_BT.Text = 'Add';
            app.AnsIVs_AddIV_LP = uilamp(app.AnsIVs_AddIV_PN);
            app.AnsIVs_IVL_Table = uitable(app.UI);
            app.AnsIVs_IVL_Table.ColumnName = {'#'; 'Date'; 'Time'};
            app.AnsIVs_IVL_Table.RowName = {};
            app.AnsIVs_TBL_Clear_BT = uibutton(app.UI, 'push');
            app.AnsIVs_TBL_Clear_BT.ButtonPushedFcn = createCallbackFcn(app, @AnsIVs_TBL_Clear_BTButtonPushed, true);
            app.AnsIVs_TBL_Clear_BT.Text = 'Clear';
            app.AnsIVs_path_fst_EF = uieditfield(app.UI, 'text');
            app.AnsIVs_path_fst_EF.Editable = 'off';
            app.AnsIVs_Save_BT = uibutton(app.UI, 'push');
            app.AnsIVs_Save_BT.ButtonPushedFcn = createCallbackFcn(app, @AnsIVs_Save_BTButtonPushed, true);
            app.AnsIVs_Save_BT.Enable = 'off';
            app.AnsIVs_Save_BT.Text = 'Save';
            app.AnsIVs_ext_RG = uibuttongroup(app.UI);
            app.AnsIVs_ext_RG.Title = 'Extension';
            app.mat_BT = uiradiobutton(app.AnsIVs_ext_RG);
            app.mat_BT.Text = '.mat';
            app.mat_BT.Value = true;
            app.fig_BT = uiradiobutton(app.AnsIVs_ext_RG);
            app.fig_BT.Text = '.fig+.jpg';
            app.AnsIVs_Save_LP = uilamp(app.UI);
            app.PathEditFieldLabel = uilabel(app.UI);
            app.PathEditFieldLabel.Text = 'Path';
            app.AnsIVs_path_snd_EF = uieditfield(app.UI, 'text');
            app.X_Ax_BG = uibuttongroup(app.UI);
            app.X_Ax_BG.Title = 'X axis';
            app.X_Ax_T_B = uitogglebutton(app.X_Ax_BG);
            app.X_Ax_T_B.Text = 'T';
            app.X_Ax_T_B.Value = true;
            app.X_Ax_G_B = uitogglebutton(app.X_Ax_BG);
            app.X_Ax_G_B.Text = 'G';
            app.Y_Ax_BG = uibuttongroup(app.UI);
            app.Y_Ax_BG.Title = 'Y axis';
            app.Y_Ax_Ic_B = uitogglebutton(app.Y_Ax_BG);
            app.Y_Ax_Ic_B.Text = 'Ic';
            app.Y_Ax_Ic_B.Value = true;
            app.Y_Ax_Irt_B = uitogglebutton(app.Y_Ax_BG);
            app.Y_Ax_Irt_B.Text = 'Irt';
            app.Y_Ax_RES_B = uitogglebutton(app.Y_Ax_BG);
            app.Y_Ax_RES_B.Text = 'RES';
            app.Y_Az_IrtIc_B = uitogglebutton(app.Y_Ax_BG);
            app.Y_Az_IrtIc_B.Text = 'IrtIc';
            app.AnsIVs_plot_BT = uibutton(app.UI, 'push');
            app.AnsIVs_plot_BT.ButtonPushedFcn = createCallbackFcn(app, @AnsIVs_plot_BTButtonPushed, true);
            app.AnsIVs_plot_BT.Text = 'Plot';
            app.AnsIVs_plot_LP = uilamp(app.UI);
            app.Mass_Load_BT = uibutton(app.UI, 'push');
            app.Mass_Load_BT.ButtonPushedFcn = createCallbackFcn(app, @Mass_Load_BTButtonPushed, true);
            app.Mass_Load_BT.Text = 'Mass Load';
        end
    end

    methods (Access = public)

        % Construct app
        function app = AnsIV_UI(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UI)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UI)
        end
    end
end