classdef AnsPL_UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UI                  matlab.ui.Figure
        an_pf_lf_p          matlab.ui.container.Panel
        DateEditFieldLabel  matlab.ui.control.Label
        AnsPul_date_EF      matlab.ui.control.EditField
        TimeEditFieldLabel  matlab.ui.control.Label
        AnsPul_time_EF      matlab.ui.control.EditField
        AnsPul_load_BT      matlab.ui.control.Button
        AnsPul_load_LP      matlab.ui.control.Lamp
        FitPulAut_BT        matlab.ui.control.Button
        L14                 matlab.ui.control.Lamp
        FitPul_man_a_NF     matlab.ui.control.NumericEditField
        FitPul_Table        matlab.ui.control.Table
        FitPul_man_b_NF     matlab.ui.control.NumericEditField
        TALabel             matlab.ui.control.Label
        MQTLabel            matlab.ui.control.Label
        ManualLabel         matlab.ui.control.Label
        FitPulMan_BT        matlab.ui.control.Button
        Lamp                matlab.ui.control.Lamp
        RnEditFieldLabel    matlab.ui.control.Label
        Rn_NF               matlab.ui.control.NumericEditField
        TEditFieldLabel     matlab.ui.control.Label
        T_NF                matlab.ui.control.NumericEditField
        v3Button            matlab.ui.control.Button
    end

    properties (Access = public)
        appMF;
    end
    properties (Access = private)
        %%
        fndp;
        ldap;
        Tp;
        Gp; % Description
        f;
        ax;
    end
%     properties (Access = private)
%         x1;
%         xTA;
%         xMQT;
%         y1;
%         y2;
%         y3;
%         yTAa;
%         yTAm;
%         yMQTa;
%         yMQTm;
%     end
    properties(Access = private)
        %Ic
    end

    methods (Access = private)
        
        function fit_old(app)
            k = tech.const.si.k;
            e = tech.const.si.e;
            h = tech.const.si.h;
            hbar = tech.const.si.hbar;
            F0 = tech.const.si.F0;
            Rn = app.Rn_NF.Value;
            d = 1.76*k*1.2;
            Ic = pi*d/(2*e*Rn)*tanh(d/(2*k*app.Tp));
            fprintf('\nDifferent approach\n');
            fprintf('Ic = %e\n',Ic)
            Ej = hbar*Ic/(2*e);
            fprintf('Ej = %e\n',Ej)
            n0 = find(app.ldap.pr > 0,1);
            n1 = find(app.ldap.pr == 1);
            app.ldap.pr(n1:end) = 0.999; 
            if isempty(n1)
                n1 = length(app.ldap.pr);
            end
            if n0 - 5 > 1
                nl0 = n0 - 5;
            else
                nl0 = 1;
            end  
            if n1 + 5 > length(app.ldap.pr)
                nl1 = length(app.ldap.pr);
            else
                nl1 = n1 + 5;
            end
            x1 = app.ldap.cur(nl0:nl1).';
            y1 = app.ldap.pr(nl0:nl1).';
            x2 = (Ic - x1).^1.5;
            x3 = (Ic - x1).^1.25;
            y2 = log(1 - y1).';
            y3 = log(1 - y1).';
            f2 = fit(x2,y2,'exp1','StartPoint',[1e20,1e-25]);
            f3 = fit(x3,y3,'exp1','StartPoint',[1e20,1e-25]); 
            y2f = 1 - exp(f2.a*exp(f2.b*x2));
            y3f = 1 - exp(f3.a*exp(f3.b*x3));
            yqf = 1 - exp(-275*exp(-2e11*x2));
            figure;
            plot(x1,y1,'b',x1,y2f,'r',x1,y3f,'m',x1,yqf,'g');
            title('Pulse fitting result')
            xlabel('I [A]');
            ylabel('P');
            disp(f2)
            disp(f3)
            a=-2e11;
            b=1e-10;
            legend('Experiment','Fitting TA','Fitting MQT');
            Tc = - ((2*F0*Ic)/(3*2*pi*k*a*Ic^1.5))*2^1.5;
            fprintf('Tco = %f\n',Tc);
            Wp = a*2*pi/1e-4;
            fprintf('Wp = %e\n',Wp );  
            Ec = ((h*275/b/0.46)^2)*(2*pi/(8*F0*Ic));
            fprintf('Ec = %e\n',Ec);
            C = ((e)^2)/(2*Ec);
            fprintf('C = %e\n',C);
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, gui)
            app.appMF = gui;
            tech.amf_pos.bot_lft(app);
            tech.state.loadState(app);
        end

        % Button pushed function: AnsPul_load_BT
        function AnsPul_load_BTButtonPushed(app, event)
            try
                clear app.Tp app.ldap app.Gp
                app.f = figure;
                app.ax = axes(app.f);
                hold(app.appMF.W_Ax,'off');
                app.AnsPul_load_LP.Color = 'Yellow';
                app.fndp{1} = app.AnsPul_date_EF.Value;
                app.fndp{2} = app.AnsPul_time_EF.Value;
                md = 'PL';
                lf = [app.appMF.JO.sf,app.fndp{1},'/',md,' _',app.fndp{2},'.mat'];
                d = load(lf,'data');
                if isfield(d.data,'pul_i')
                    data.cur = d.data.pul_i;
                end
                if isfield(d.data,'cur')
                    data.cur = d.data.cur;
                end
                if iscolumn(d.data.pr)
                    data.pr = d.data.pr;
                else
                    data.pr = d.data.pr.';
                end
                app.ldap = data;
                app.Tp = load(lf,'T1');
                for j=1:length(app.ldap.pr)
                    if app.ldap.pr(j) == 1
                        app.ldap.pr(j) = 0.9999;
                    end
                    if app.ldap.pr(j) == 0
                        app.ldap.pr(j) = 0.0001;
                    end
                end
                app.Tp = app.Tp.T1;
                app.T_NF.Value = app.Tp;
                app.Gp = load(lf,'gv');
                app.Gp = app.Gp.gv;
                plot(app.ax,app.ldap.cur,app.ldap.pr)
                app.AnsPul_load_LP.Color = 'Green';
            catch ME
                app.AnsPul_load_LP.Color = 'Red';
                app.appMF.err(ME);
            end
        end

        % Button pushed function: FitPulAut_BT
        function FitPulAut_BTPushed(app, event)
            k = tech.const.si.k;
            e = tech.const.si.e;
            h = tech.const.si.h;
            hbar = tech.const.si.hbar;
            F0 = tech.const.si.F0;
            Rn = app.Rn_NF.Value;
            d = 1.76*k*1.2;
            Ic = pi*d/(2*e*Rn)*tanh(d/(2*k*app.Tp));
            fprintf('Ic = %e\n',Ic)
            Ej = F0*Ic/(2*pi);
            fprintf('Ej = %e\n',Ej)
            n0 = find(app.ldap.pr > 0,1);
            n1 = find(app.ldap.pr == 1);
            app.ldap.pr(n1:end) = 0.999; 
            if isempty(n1)
                n1 = length(app.ldap.pr);
            end
            if n0 - 5 > 1
                nl0 = n0 - 5;
            else
                nl0 = 1;
            end
            if n1 + 5 > length(app.ldap.pr)
                nl1 = length(app.ldap.pr);
            else
                nl1 = n1 + 5;
            end
            x1 = app.ldap.cur(nl0:nl1).';
            y1 = app.ldap.pr(nl0:nl1).';
            xTA = (Ic - x1).^0.25;
            xMQT = (Ic - x1).^0.5;
            yF = log(1 - y1).';
            g = fittype('a*x*exp(b*(x^3))',...
                                'dependent',{'y'},...
                                    'independent',{'x'},...
                                                'coefficients',{'a','b'});
            r = fittype('a*x*exp(b*(x^3))',...
                                'dependent',{'y'},...
                                    'independent',{'x'},...
                                                'coefficients',{'a','b'});                                  
            fTA = fit(xTA,yF,g);%'exp1');
            fMQT = fit(xMQT,yF,r);%'exp1');
            app.FitPul_Table.Data = {fTA.a,fTA.b;fMQT.a,fMQT.b};
            yTAa = 1 - exp(fTA.a*exp(fTA.b*xTA));
            yMQTa = 1 - exp(fMQT.a*exp(fMQT.b*xMQT));
            am = app.FitPul_man_a_NF.Value;
            bm = app.FitPul_man_b_NF.Value;
            yTAm = 1 - exp(bm*exp(am*xTA));
            yMQTm = 1 - exp(bm*exp(am*xMQT));
            %% TA
            fprintf('TA:\n')
            TA.Tc = - hbar*fTA.a/k;
            fprintf('Tc = %f\n',TA.Tc);
            TA.Wp = fTA.a*2*pi/1e-4;
            fprintf('Wp = %e\n',TA.Wp ); 
            TA.Ec = ((h*275/fTA.b/0.46)^2)*(2*pi/(8*F0*Ic));
            fprintf('Ec = %e\n',TA.Ec);
            TA.C = ((e)^2)/(2*TA.Ec); 
            fprintf('C = %e\n',TA.C); 
            %% MQT
            fprintf('MQT:\n')
            MQT.Tc =  - hbar*fMQT.a/k;
            fprintf('Tc = %f\n',MQT.Tc);
            MQT.Ec = ((h*275/fMQT.b/0.46)^2)*(2*pi/(8*F0*Ic));
            fprintf('Ec = %e\n',MQT.Ec);
            MQT.C = ((e)^2)/(2*MQT.Ec);
            fprintf('C = %e\n',MQT.C); 
            plot(app.ax,x1,y1,'b',x1,yTAa,'r',x1,yMQTa,'m',x1,yTAm,'g',x1,yMQTm,'black');
            hold(app.ax,'on');
            title(app.ax,[app.fndp{1},' ',app.fndp{2},' Pulse fitting result'])
            xlabel(app.ax,'I [A]');
            ylabel(app.ax,'P');
            legend(app.ax,'Experiment','Fitting TA','Fitting MQT','Manual Fitting TA','Manual Fitting MQT');
        end

        % Button pushed function: FitPulMan_BT
        function FitPul2_BTPushed(app, event)
            k = tech.const.si.k;
            e = tech.const.si.e;
            h = tech.const.si.h;
            hbar = tech.const.si.hbar;
            F0 = tech.const.si.F0;
            Rn = app.Rn_NF.Value;
            d = 1.76*k*1.2;
            Ic = pi*d/(2*e*Rn)*tanh(d/(2*k*app.Tp));
            fprintf('\nDifferent approach\n');
            fprintf('Ic = %e\n',Ic)
            Ej = hbar*Ic/(2*e);
            fprintf('Ej = %e\n',Ej)
            n0 = find(app.ldap.pr > 0,1);
            n1 = find(app.ldap.pr == 1);
            app.ldap.pr(n1:end) = 0.999; 
            if isempty(n1)
                n1 = length(app.ldap.pr);
            end
            if n0 - 5 > 1
                nl0 = n0 - 5;
            else
                nl0 = 1;
            end
            if n1 + 5 > length(app.ldap.pr)
                nl1 = length(app.ldap.pr);
            else
                nl1 = n1 + 5;
            end
            x1 = app.ldap.cur(nl0:nl1).';
            y1 = app.ldap.pr(nl0:nl1).';
            nu = x1./Ic;
            y = log(log(1 - y1)).';
            dU = @(x) 2*Ej*(sqrt(1-x.^2)-x.*acos(x));
            %t = 1e-4;
            TA.ft = fittype(@(a,x) log(a*((1-x.^2).^0.25)) - dU(x)/(k*app.Tp));
            TA.f = fit(nu,y,TA.ft);
            %TA.y = 1 - exp((1e-4/(2*pi))*sqrt(2*e*Ic/(hbar*TA.f.c))*((1-nu.^2).^0.25).*(exp(-dU(nu)/(k*app.Tp))));
            plot(TA.f,nu,y,'b');
            hold(app.ax,'on');
            title(app.ax,[app.fndp{1},' ',app.fndp{2},' Pulse fitting result'])
            xlabel(app.ax,'I [A]');
            ylabel(app.ax,'P');
            legend(app.ax,'Experiment','Fitting TA');
            TA.C = TA.f.a; 
            fprintf('C = %e\n',TA.C);
        end

        % Close request function: UI
        function UICloseRequest(app, event)
            app.appMF.AnsPulMenu.Enable = 'on';
            tech.state.saveState(app);
            delete(app);
        end

        % Button pushed function: v3Button
        function v3ButtonPushed(app, event)
            data = app.ldap;
            Tp = app.Tp;
            Rn = app.Rn_NF.Value;
            TA = anl.PL.PLfit(data,Tp,Rn,app.ax); 
            %title(app.ax,[app.fndp{1},' ',app.fndp{2},' Pulse fitting result'])
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UI = uifigure;
            app.UI.Name = 'Pulse fitting';
            app.UI.CloseRequestFcn = createCallbackFcn(app, @UICloseRequest, true);
            app.an_pf_lf_p = uipanel(app.UI);
            app.an_pf_lf_p.Title = 'Load file';
            app.DateEditFieldLabel = uilabel(app.an_pf_lf_p);
            app.DateEditFieldLabel.Text = 'Date';
            app.AnsPul_date_EF = uieditfield(app.an_pf_lf_p, 'text');
            app.TimeEditFieldLabel = uilabel(app.an_pf_lf_p);
            app.TimeEditFieldLabel.Text = 'Time';
            app.AnsPul_time_EF = uieditfield(app.an_pf_lf_p, 'text');
            app.AnsPul_load_BT = uibutton(app.an_pf_lf_p, 'push');
            app.AnsPul_load_BT.ButtonPushedFcn = createCallbackFcn(app, @AnsPul_load_BTButtonPushed, true);
            app.AnsPul_load_BT.Text = 'Load';
            app.AnsPul_load_LP = uilamp(app.an_pf_lf_p);
            app.FitPulAut_BT = uibutton(app.UI, 'push');
            app.FitPulAut_BT.ButtonPushedFcn = createCallbackFcn(app, @FitPulAut_BTPushed, true);
            app.FitPulAut_BT.Text = 'AutoFit';
            app.L14 = uilamp(app.UI);
            app.FitPul_man_a_NF = uieditfield(app.UI, 'numeric');
            app.FitPul_Table = uitable(app.UI);
            app.FitPul_Table.ColumnName = {'a'; 'b'};
            app.FitPul_Table.RowName = {};
            app.FitPul_man_b_NF = uieditfield(app.UI, 'numeric');
            app.TALabel = uilabel(app.UI);
            app.TALabel.Text = 'TA';
            app.MQTLabel = uilabel(app.UI);
            app.MQTLabel.Text = 'MQT';
            app.ManualLabel = uilabel(app.UI);
            app.ManualLabel.Text = 'Manual';
            app.FitPulMan_BT = uibutton(app.UI, 'push');
            app.FitPulMan_BT.ButtonPushedFcn = createCallbackFcn(app, @FitPul2_BTPushed, true);
            app.FitPulMan_BT.Text = 'v2';
            app.Lamp = uilamp(app.UI);
            app.RnEditFieldLabel = uilabel(app.UI);
            app.RnEditFieldLabel.Text = 'Rn';
            app.Rn_NF = uieditfield(app.UI, 'numeric');
            app.Rn_NF.ValueDisplayFormat = '%5.1f';
            app.TEditFieldLabel = uilabel(app.UI);
            app.TEditFieldLabel.Text = 'T';
            app.T_NF = uieditfield(app.UI, 'numeric');
            app.T_NF.ValueDisplayFormat = '%5.1f';
            app.v3Button = uibutton(app.UI, 'push');
            app.v3Button.ButtonPushedFcn = createCallbackFcn(app, @v3ButtonPushed, true);
            app.v3Button.Text = 'v3';
        end
    end

    methods (Access = public)

        % Construct app
        function app = AnsPL_UI(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UI)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UI)
        end
    end
end