classdef JouBND_UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UI         matlab.ui.Figure
        bnd_Ax     matlab.ui.control.UIAxes
        I_LL       matlab.ui.control.Label
        I1_NF      matlab.ui.control.NumericEditField
        V1_NF      matlab.ui.control.NumericEditField
        V_LL       matlab.ui.control.Label
        G_LL       matlab.ui.control.Label
        G1_NF      matlab.ui.control.NumericEditField
        I2_NF      matlab.ui.control.NumericEditField
        V2_NF      matlab.ui.control.NumericEditField
        G2_NF      matlab.ui.control.NumericEditField
        sb_BT      matlab.ui.control.StateButton
        bnd_LP     matlab.ui.control.Lamp
        bnd_tj_CB  matlab.ui.control.CheckBox
    end


    properties (Access = public)
        appMF; % Description
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, gui)
            app.appMF = gui;
            imshow('Pics/pins.png','Parent',app.bnd_Ax);
            tech.amf_pos.rgt_top(app);
            tech.state.loadState(app);
        end

        % Value changed function: sb_BT
        function sb_BTValueChanged(app, event)
            val = app.sb_BT.Value;
            switch val
                case 1
                    try
                        app.bnd_LP.Color = 'Yellow';
                        app.appMF.JO.bondi.I = [app.I1_NF.Value app.I2_NF.Value];
                        app.appMF.JO.bondi.V = [app.V1_NF.Value app.V2_NF.Value];
                        app.appMF.JO.bondi.G = [app.G1_NF.Value app.G2_NF.Value];
                        app.I1_NF.Editable = 'off';
                        app.I2_NF.Editable = 'off';
                        app.V1_NF.Editable = 'off';
                        app.V2_NF.Editable = 'off';
                        app.G1_NF.Editable = 'off';
                        app.G2_NF.Editable = 'off';
                        app.bnd_LP.Color = 'Green';
                        if app.bnd_tj_CB.Value
                            fprintf(app.appMF.joGen.func,'Bonding\n');
                            fprintf(app.appMF.joGen.func,'Current %d   %d\n',app.appMF.JO.bondi.I(1),app.appMF.JO.bondi.I(2));
                            fprintf(app.appMF.joGen.func,'Voltage %d   %d\n',app.appMF.JO.bondi.V(1),app.appMF.JO.bondi.V(2));
                            fprintf(app.appMF.joGen.func,'Gate    %d   %d\n',app.appMF.JO.bondi.G(1),app.appMF.JO.bondi.G(2));
                            fprintf(app.appMF.joGen.func,'-------------------------\n');
                        end
                        app.appMF.MeasurementsMenu.Enable = 'on';
                    catch ME
                        app.bnd_LP.Color = 'Red';
                        app.appMF.err(ME);
                    end
                case 0
                    app.I1_NF.Editable = 'on';
                    app.I2_NF.Editable = 'on';
                    app.V1_NF.Editable = 'on';
                    app.V2_NF.Editable = 'on';
                    app.G1_NF.Editable = 'on';
                    app.G2_NF.Editable = 'on';
                    app.bnd_LP.Color = 'Blue';
            end
        end

        % Close request function: UI
        function UICloseRequest(app, event)
            app.appMF.BondingMenu.Enable = 'on';
            tech.state.saveState(app);
            delete(app);            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UI = uifigure;
            app.UI.Name = 'Bonds';
            app.UI.CloseRequestFcn = createCallbackFcn(app, @UICloseRequest, true);
            app.bnd_Ax = uiaxes(app.UI);
            title(app.bnd_Ax, 'Title')
            app.I_LL = uilabel(app.UI);
            app.I_LL.Text = 'I';
            app.I1_NF = uieditfield(app.UI, 'numeric');
            app.I1_NF.Limits = [0 14];
            app.I1_NF.Value = 1;
            app.V1_NF = uieditfield(app.UI, 'numeric');
            app.V1_NF.Limits = [0 14];
            app.V1_NF.Value = 2;
            app.V_LL = uilabel(app.UI);
            app.V_LL.Text = 'V';
            app.G_LL = uilabel(app.UI);
            app.G_LL.Text = 'G';
            app.G1_NF = uieditfield(app.UI, 'numeric');
            app.G1_NF.Limits = [0 14];
            app.G1_NF.Value = 3;
            app.I2_NF = uieditfield(app.UI, 'numeric');
            app.I2_NF.Value = 7;
            app.V2_NF = uieditfield(app.UI, 'numeric');
            app.V2_NF.Limits = [0 14];
            app.V2_NF.Value = 8;
            app.G2_NF = uieditfield(app.UI, 'numeric');
            app.G2_NF.Limits = [0 14];
            app.G2_NF.Value = 4;
            app.sb_BT = uibutton(app.UI, 'state');
            app.sb_BT.ValueChangedFcn = createCallbackFcn(app, @sb_BTValueChanged, true);
            app.sb_BT.Text = 'Set';
            app.bnd_LP = uilamp(app.UI);
            app.bnd_tj_CB = uicheckbox(app.UI);
            app.bnd_tj_CB.Text = 'To Journal';
        end
    end

    methods (Access = public)

        % Construct app
        function app = JouBND_UI(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UI)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UI)
        end
    end
end