classdef JouJAN_UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UI      matlab.ui.Figure
        JAN_PN  matlab.ui.container.Panel
        JAN_TA  matlab.ui.control.TextArea
        JAN_BT  matlab.ui.control.Button
        JAN_LP  matlab.ui.control.Lamp
    end


    properties (Access = public)
        appMF; % GUI MF
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, gui)
            app.appMF = gui;
            tech.amf_pos.bot_lft(app);
        end

        % Close request function: UI
        function UICloseRequest(app, event)
            app.appMF.AddNoteMenu.Enable = 'on'; 
            delete(app);
        end

        % Button pushed function: JAN_BT
        function JAN_BTButtonPushed(app, event)
            try
                app.JAN_LP.Color = 'Yellow';
                fprintf(app.appMF.JO.joGen.func,'-------------------------\n');
                for i = 1:size(app.JAN_TA.Value)
                    fprintf(app.appMF.JO.joGen.func,'%s\n',app.JAN_TA.Value{i});
                end
                fprintf(app.appMF.JO.joGen.func,'-------------------------\n');
            catch ME
                app.JAN_LP.Color = 'Red';
                app.appMF.err(app,ME);
            end
            app.JAN_LP.Color = 'Green';
            pause(2);
            app.JAN_LP.Color = 'White';
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UI = uifigure;
            app.UI.Name = 'Journal Note';
            app.UI.CloseRequestFcn = createCallbackFcn(app, @UICloseRequest, true);
            app.JAN_PN = uipanel(app.UI);
            app.JAN_PN.Title = 'Add note to log';
            app.JAN_TA = uitextarea(app.JAN_PN);
            app.JAN_TA.Value = {'Annealing'};
            app.JAN_BT = uibutton(app.JAN_PN, 'push');
            app.JAN_BT.ButtonPushedFcn = createCallbackFcn(app, @JAN_BTButtonPushed, true);
            app.JAN_BT.Text = 'Add';
            app.JAN_LP = uilamp(app.JAN_PN);
        end
    end

    methods (Access = public)

        % Construct app
        function app = JouJAN_UI(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UI)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UI)
        end
    end
end