classdef JouJSP_UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UI               matlab.ui.Figure
        JSP_PN           matlab.ui.container.Panel
        LabelEditField2  matlab.ui.control.Label
        JSP_df_TF        matlab.ui.control.EditField
        LabelEditField3  matlab.ui.control.Label
        JSP_sn_TF        matlab.ui.control.EditField
        JSP_mj_BT        matlab.ui.control.Button
        JSP_mj_LP        matlab.ui.control.Lamp
        LabelTextArea    matlab.ui.control.Label
        JSP_sn_TA        matlab.ui.control.TextArea
        sc_PN            matlab.ui.container.ButtonGroup
        cy_RB            matlab.ui.control.RadioButton
        cn_RB            matlab.ui.control.RadioButton
        cp_RB            matlab.ui.control.RadioButton
        pc_TF            matlab.ui.control.EditField
        sc_BT            matlab.ui.control.Button
        sc_LP            matlab.ui.control.Lamp
    end

    properties (Access = public)
        appMF; % Description
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, gui)
            app.appMF = gui;
            tech.amf_pos.bot_lft(app);
            tech.state.loadState(app);
        end

        % Close request function: UI
        function UICloseRequest(app, event)
            app.appMF.SetupMenu.Enable = 'on';
            tech.state.saveState(app);
            delete(app);
        end

        % Button pushed function: JSP_mj_BT
        function JSP_mj_BTButtonPushed(app, event)
            try
                app.JSP_mj_LP.Color = 'Yellow';
                app.appMF.JO.df = ['C:/Users/',getenv('USERNAME'),'/MATLAB Drive/',app.JSP_df_TF.Value]; % Main data folder
                app.appMF.JO.sn = app.JSP_sn_TF.Value; % Sample name
                app.appMF.JO.sf = [ app.appMF.JO.df, app.appMF.JO.sn,'/']; % folder to save results
                app.appMF.JO.JN = app.JSP_sn_TA.Value;
                %app.an_path_up.Value = [app.ss,'Results/'];
                mkdir(app.appMF.JO.sf);
                app.appMF.MstIVsMenu.Enable = 'on';
                app.appMF.MstPulMenu.Enable = 'on';
                app.appMF.JO.joGen = jou.journal([app.appMF.JO.sf,'Gen'],'.jo');
                if ftell(app.appMF.JO.joGen.func) == 0
                    fprintf(app.appMF.JO.joGen.func,'Sample %s\n',app.appMF.JO.sn);
                    fprintf(app.appMF.JO.joGen.func,'-------------------------\n');
                    for q = 1:size(app.appMF.JO.JN)
                        fprintf(app.appMF.JO.joGen.func,'%s\n',app.appMF.JO.JN{q});
                    end
                end
                app.appMF.JO.joIVs = jou.journal([app.appMF.JO.sf,'IV'],'.jo');
                if ftell(app.appMF.JO.joIVs.func) == 0
                    fprintf(app.appMF.JO.joIVs.func,'Sample %s\n',app.appMF.JO.sn);
                    fprintf(app.appMF.JO.joIVs.func,'-------------------------\n');
                end
                app.appMF.JO.joPul = jou.journal([app.appMF.JO.sf,'PL'],'.jo');
                if ftell(app.appMF.JO.joPul.func) == 0
                    fprintf(app.appMF.JO.joPul.func,'Sample %s\n',app.appMF.JO.sn);
                    fprintf(app.appMF.JO.joPul.func,'-------------------------\n');
                end
                app.appMF.JO.lgTem = jou.journal([app.appMF.JO.sf,'T'],'.lg');
                app.appMF.BondingMenu.Enable = 'on';
                app.appMF.AnsMenu.Enable = 'on';
                app.appMF.MstTemMenu.Enable = 'on';
                app.JSP_mj_LP.Color = 'Green';
                app.appMF.AddNoteMenu.Enable = 'on';
            catch ME
                app.JSP_mj_LP.Color = 'Red';
                app.appMF.err(ME);
            end
        end

        % Button pushed function: sc_BT
        function sc_BTButtonPushed(app, event)
            try
                app.sc_LP.Color = 'Yellow';
                switch app.sc_PN.SelectedObject.Text
                    case 'Yes'
                        app.appMF.JO.co = 'y';
                    case 'No'
                        app.appMF.JO.co = 'n';
                    case 'Preset'
                        app.appMF.JO.co = app.pc_TF.Value;
                end
                app.sc_LP.Color = 'Green';
            catch ME
                app.sc_LP.Color = 'Red';
                app.appMF.err(ME);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UI = uifigure;
            app.UI.Name = 'Journal Setup';
            app.UI.CloseRequestFcn = createCallbackFcn(app, @UICloseRequest, true);
            app.JSP_PN = uipanel(app.UI);
            app.JSP_PN.Title = 'Journal path and notes';
            app.LabelEditField2 = uilabel(app.JSP_PN);
            app.LabelEditField2.Text = 'Data folder';
            app.JSP_df_TF = uieditfield(app.JSP_PN, 'text');
            app.JSP_df_TF.Value = 'Data/';
            app.LabelEditField3 = uilabel(app.JSP_PN);
            app.LabelEditField3.Text = 'Sample name';
            app.JSP_sn_TF = uieditfield(app.JSP_PN, 'text');
            app.JSP_sn_TF.Value = 'MT1';
            app.JSP_mj_BT = uibutton(app.JSP_PN, 'push');
            app.JSP_mj_BT.ButtonPushedFcn = createCallbackFcn(app, @JSP_mj_BTButtonPushed, true);
            app.JSP_mj_BT.Text = 'Make';
            app.JSP_mj_LP = uilamp(app.JSP_PN);
            app.LabelTextArea = uilabel(app.JSP_PN);
            app.LabelTextArea.Text = 'Sample notes';
            app.JSP_sn_TA = uitextarea(app.JSP_PN);
            app.JSP_sn_TA.Value = {'Sample by'};
            app.sc_PN = uibuttongroup(app.UI);
            app.sc_PN.Title = 'Comments';
            app.cy_RB = uiradiobutton(app.sc_PN);
            app.cy_RB.Text = 'Yes';
            app.cy_RB.Value = true;
            app.cn_RB = uiradiobutton(app.sc_PN);
            app.cn_RB.Text = 'No';
            app.cp_RB = uiradiobutton(app.sc_PN);
            app.cp_RB.Text = 'Preset';
            app.pc_TF = uieditfield(app.sc_PN, 'text');
            app.pc_TF.Value = 'meas';
            app.sc_BT = uibutton(app.UI, 'push');
            app.sc_BT.ButtonPushedFcn = createCallbackFcn(app, @sc_BTButtonPushed, true);
            app.sc_BT.Text = 'Set';
            app.sc_LP = uilamp(app.UI);
        end
    end

    methods (Access = public)

        % Construct app
        function app = JouJSP_UI(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UI)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UI)
        end
    end
end