classdef MstIV_UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UI                       matlab.ui.Figure
        IVs_opt_PN               matlab.ui.container.Panel
        IVs_par_PN               matlab.ui.container.Panel
        LabelNumericEditField10  matlab.ui.control.Label
        IVs_par_amp_NF           matlab.ui.control.NumericEditField
        LabelEditField5          matlab.ui.control.Label
        IVs_par_gv_NF            matlab.ui.control.EditField
        LabelNumericEditField11  matlab.ui.control.Label
        IVs_par_st_NF            matlab.ui.control.NumericEditField
        GatepauseEditFieldLabel  matlab.ui.control.Label
        IVs_par_gp_NF            matlab.ui.control.NumericEditField
        IVs_mt_RG                matlab.ui.container.ButtonGroup
        IVs_mt_IV_RB             matlab.ui.control.RadioButton
        IVs_mt_RES_RB            matlab.ui.control.RadioButton
        IVs_opt_so_LP            matlab.ui.control.Lamp
        IVs_opt_ivs_PN           matlab.ui.container.Panel
        IVs_opt_ivs_NF           matlab.ui.control.NumericEditField
        IVs_opt_repeat_pause_NF  matlab.ui.control.NumericEditField
        IVs_opt_rpt_CB           matlab.ui.control.CheckBox
        IVs_opt_so_BT            matlab.ui.control.StateButton
        IVs_opt_pol_CB           matlab.ui.control.CheckBox
        IVs_mst_PN               matlab.ui.container.Panel
        IVs_mst_start_BT         matlab.ui.control.Button
        IVs_mst_LP               matlab.ui.control.Lamp
        IVs_mst_stop_BT          matlab.ui.control.StateButton
        IVs_aco_PN               matlab.ui.container.Panel
        IVs_aco_TF               matlab.ui.control.EditField
        IVs_aco_BT               matlab.ui.control.Button
        LabelNumericEditField15  matlab.ui.control.Label
        IVs_mst_res_NF           matlab.ui.control.NumericEditField
        LabelNumericEditField21  matlab.ui.control.Label
        IVs_mst_Isw_NF           matlab.ui.control.NumericEditField
        LabelNumericEditField22  matlab.ui.control.Label
        IVs_mst_Irt_NF           matlab.ui.control.NumericEditField
        LabelNumericEditField16  matlab.ui.control.Label
        IVs_mst_T1_NF            matlab.ui.control.NumericEditField
        LabelNumericEditField17  matlab.ui.control.Label
        IVs_mst_T2_NF            matlab.ui.control.NumericEditField
        BREAK_BT                 matlab.ui.control.StateButton
    end

    properties (Access = public)
        appMF; % Description
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, gui)
            app.appMF = gui;
            tech.amf_pos.bot_lft(app);
            tech.state.loadState(app);
            app.appMF.MstIVsMenu.Enable = 'off';
        end

        % Button pushed function: IVs_mst_start_BT
        function IVs_mst_start_BTButtonPushed(app, event)
            try
                i = 1;
                cla(app.appMF.W_Ax);
                delete(findall(app.appMF.W_Ax,'Type','Text'));
                app.IVs_mst_LP.Color = 'Yellow';
                app.IVs_mst_start_BT.Enable = 'off';
                app.IVs_mst_stop_BT.Enable = 'on';
                imshow('wip.jpg','Parent',app.appMF.W_Ax);%ax);
                while (app.IVs_opt_rpt_CB.Value) || (i)
                    switch app.IVs_mt_RG.SelectedObject.Text
                        case 'IV'
                            mst.IV.ivs(app.appMF);
                        case 'Resistance'
                            mst.IV.res(app.appMF);
                    end
                    switch app.appMF.JO.co
                        case 'y'
                            app.IVs_aco_PN.Visible = 'on';
                        otherwise
                            app.appMF.JO.joIVs.add_com;
                    end
                    pause(app.IVs_opt_repeat_pause_NF.Value*60);
                    i = 0;
                end
                app.IVs_mst_stop_BT.Enable = 'off';
                imshow('nip.jpg','Parent',app.appMF.W_Ax);
                app.IVs_mst_LP.Color = 'Green';
            catch ME
                app.IVs_mst_LP.Color = 'Red';
                app.appMF.err(ME);
            end
        end

        % Value changed function: IVs_opt_so_BT
        function IVs_opt_so_BTButtonPushed(app, event)
            val = app.IVs_opt_so_BT.Value;
            switch val
                case 1
                    try
                        app.IVs_opt_so_LP.Color = 'Yellow';
                        app.appMF.IV.ivsp = app.IVs_opt_ivs_NF.Value;
                        app.appMF.IV.gp = app.IVs_par_gp_NF.Value;
                        app.appMF.IV.amp = app.IVs_par_amp_NF.Value;
                        app.appMF.IV.st = app.IVs_par_st_NF.Value;
                        app.appMF.IV.gv = str2num(app.IVs_par_gv_NF.Value);
                        app.appMF.IV.pola = app.IVs_opt_pol_CB.Value;
                        app.IVs_opt_so_LP.Color = 'Green';
                        app.IVs_par_amp_NF.Editable = 'off';
                        app.IVs_par_gv_NF.Editable = 'off';
                        app.IVs_par_st_NF.Editable = 'off';
                        app.IVs_par_gp_NF.Editable = 'off';
                        app.IVs_mt_IV_RB.Enable = 'off';
                        app.IVs_mt_RES_RB.Enable = 'off';
                        app.IVs_opt_pol_CB.Enable = 'off';
                        app.IVs_mst_start_BT.Enable = 'on';
                    catch ME
                        app.IVs_opt_so_LP.Color = 'Red';
                        app.appMF.err(ME);
                    end
                case 0
                    app.IVs_par_amp_NF.Editable = 'on';
                    app.IVs_par_gv_NF.Editable = 'on';
                    app.IVs_par_st_NF.Editable = 'on';
                    app.IVs_par_gp_NF.Editable = 'on';
                    app.IVs_mt_IV_RB.Enable = 'on';
                    app.IVs_mt_RES_RB.Enable = 'on';
                    app.IVs_opt_pol_CB.Enable = 'on';
                    app.IVs_opt_so_LP.Color = 'Blue';
            end
        end

        % Close request function: UI
        function UICloseRequest(app, event)
            try
                app.appMF.MstIVsMenu.Enable = 'on';
                tech.state.saveState(app);
                delete(app);
            catch ME
                app.appMF.err(ME);
            end
        end

        % Value changed function: IVs_mst_stop_BT
        function IVs_mst_stop_BTValueChanged(app, event)
            val = app.IVs_mst_stop_BT.Value;
            switch val
                case 1
                    app.BREAK_BT.Enable = 'on';
                    warning('mstIVstop:on','Measurements will STOP');
                case 0
                    app.BREAK_BT.Enable = 'off';
                    warning('mstIVstop:off','Measurements will NOT STOP')
            end
        end

        % Button pushed function: IVs_aco_BT
        function IVs_aco_BTButtonPushed(app, event)
            app.appMF.JO.joIVs.add_com(app.IVs_aco_TF.Value,app.appMF.JO.co);
            app.IVs_aco_TF.Value = '';
            app.IVs_aco_PN.Visible = 'off';
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UI = uifigure;
            app.UI.Name = 'IV and Res measurements';
            app.UI.CloseRequestFcn = createCallbackFcn(app, @UICloseRequest, true);
            app.IVs_opt_PN = uipanel(app.UI);
            app.IVs_opt_PN.Title = 'Measurement options';
            app.IVs_par_PN = uipanel(app.IVs_opt_PN);
            app.IVs_par_PN.Title = 'Parameters';
            app.LabelNumericEditField10 = uilabel(app.IVs_par_PN);
            app.LabelNumericEditField10.Text = 'Amplitude';
            app.IVs_par_amp_NF = uieditfield(app.IVs_par_PN, 'numeric');
            app.IVs_par_amp_NF.Value = 1;
            app.LabelEditField5 = uilabel(app.IVs_par_PN);
            app.LabelEditField5.Text = 'Gate';
            app.IVs_par_gv_NF = uieditfield(app.IVs_par_PN, 'text');
            app.IVs_par_gv_NF.Value = '0';
            app.LabelNumericEditField11 = uilabel(app.IVs_par_PN);
            app.LabelNumericEditField11.Text = 'Step';
            app.IVs_par_st_NF = uieditfield(app.IVs_par_PN, 'numeric');
            app.IVs_par_st_NF.Value = 0.01;
            app.GatepauseEditFieldLabel = uilabel(app.IVs_par_PN);
            app.GatepauseEditFieldLabel.Text = 'Gate pause';
            app.IVs_par_gp_NF = uieditfield(app.IVs_par_PN, 'numeric');
            app.IVs_par_gp_NF.Value = 30;
            app.IVs_mt_RG = uibuttongroup(app.IVs_opt_PN);
            app.IVs_mt_RG.Title = 'Measurement';
            app.IVs_mt_IV_RB = uiradiobutton(app.IVs_mt_RG);
            app.IVs_mt_IV_RB.Text = 'IV';
            app.IVs_mt_IV_RB.Value = true;
            app.IVs_mt_RES_RB = uiradiobutton(app.IVs_mt_RG);
            app.IVs_mt_RES_RB.Text = 'Resistance';
            app.IVs_opt_so_LP = uilamp(app.IVs_opt_PN);
            app.IVs_opt_ivs_PN = uipanel(app.IVs_opt_PN);
            app.IVs_opt_ivs_PN.Title = 'IV speed';
            app.IVs_opt_ivs_NF = uieditfield(app.IVs_opt_ivs_PN, 'numeric');
            app.IVs_opt_ivs_NF.Value = 0.05;
            app.IVs_opt_repeat_pause_NF = uieditfield(app.IVs_opt_PN, 'numeric');
            app.IVs_opt_repeat_pause_NF.Limits = [0 Inf];
            app.IVs_opt_rpt_CB = uicheckbox(app.IVs_opt_PN);
            app.IVs_opt_rpt_CB.Text = 'Repeat';
            app.IVs_opt_so_BT = uibutton(app.IVs_opt_PN, 'state');
            app.IVs_opt_so_BT.ValueChangedFcn = createCallbackFcn(app, @IVs_opt_so_BTButtonPushed, true);
            app.IVs_opt_so_BT.Text = 'Set';
            app.IVs_opt_pol_CB = uicheckbox(app.IVs_opt_PN);
            app.IVs_opt_pol_CB.Text = 'Polarity';
            app.IVs_mst_PN = uipanel(app.UI);
            app.IVs_mst_PN.Title = 'Measurement';
            app.IVs_mst_start_BT = uibutton(app.IVs_mst_PN, 'push');
            app.IVs_mst_start_BT.ButtonPushedFcn = createCallbackFcn(app, @IVs_mst_start_BTButtonPushed, true);
            app.IVs_mst_start_BT.Enable = 'off';
            app.IVs_mst_start_BT.Text = 'Start';
            app.IVs_mst_LP = uilamp(app.IVs_mst_PN);
            app.IVs_mst_stop_BT = uibutton(app.IVs_mst_PN, 'state');
            app.IVs_mst_stop_BT.ValueChangedFcn = createCallbackFcn(app, @IVs_mst_stop_BTValueChanged, true);
            app.IVs_mst_stop_BT.Enable = 'off';
            app.IVs_mst_stop_BT.Text = 'Stop';
            app.IVs_aco_PN = uipanel(app.IVs_mst_PN);
            app.IVs_aco_PN.Title = 'Comments';
            app.IVs_aco_PN.Visible = 'off';
            app.IVs_aco_TF = uieditfield(app.IVs_aco_PN, 'text');
            app.IVs_aco_BT = uibutton(app.IVs_aco_PN, 'push');
            app.IVs_aco_BT.ButtonPushedFcn = createCallbackFcn(app, @IVs_aco_BTButtonPushed, true);
            app.IVs_aco_BT.Text = 'Add';
            app.LabelNumericEditField15 = uilabel(app.IVs_mst_PN);
            app.LabelNumericEditField15.Text = 'RES';
            app.IVs_mst_res_NF = uieditfield(app.IVs_mst_PN, 'numeric');
            app.IVs_mst_res_NF.ValueDisplayFormat = '%2.3f';
            app.IVs_mst_res_NF.Editable = 'off';
            app.LabelNumericEditField21 = uilabel(app.IVs_mst_PN);
            app.LabelNumericEditField21.Text = 'Isw';
            app.IVs_mst_Isw_NF = uieditfield(app.IVs_mst_PN, 'numeric');
            app.IVs_mst_Isw_NF.ValueDisplayFormat = '%.2e';
            app.IVs_mst_Isw_NF.Editable = 'off';
            app.LabelNumericEditField22 = uilabel(app.IVs_mst_PN);
            app.LabelNumericEditField22.Text = 'Irt';
            app.IVs_mst_Irt_NF = uieditfield(app.IVs_mst_PN, 'numeric');
            app.IVs_mst_Irt_NF.ValueDisplayFormat = '%.2e';
            app.IVs_mst_Irt_NF.Editable = 'off';
            app.LabelNumericEditField16 = uilabel(app.IVs_mst_PN);
            app.LabelNumericEditField16.Text = 'T1';
            app.IVs_mst_T1_NF = uieditfield(app.IVs_mst_PN, 'numeric');
            app.IVs_mst_T1_NF.Editable = 'off';
            app.LabelNumericEditField17 = uilabel(app.IVs_mst_PN);
            app.LabelNumericEditField17.Text = 'T2';
            app.IVs_mst_T2_NF = uieditfield(app.IVs_mst_PN, 'numeric');
            app.IVs_mst_T2_NF.Editable = 'off';
            app.BREAK_BT = uibutton(app.IVs_mst_PN, 'state');
            app.BREAK_BT.Enable = 'off';
            app.BREAK_BT.Text = 'BREAK';
        end
    end

    methods (Access = public)

        % Construct app
        function app = MstIV_UI(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UI)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UI)
        end
    end
end