classdef MstPL_UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UI                       matlab.ui.Figure
        PL_so_LP                 matlab.ui.control.Lamp
        PL_par_PN                matlab.ui.container.Panel
        PL_par_TB                matlab.ui.container.TabGroup
        PL_par_1_TB              matlab.ui.container.Tab
        LabelEditField           matlab.ui.control.Label
        PL_cur_NF                matlab.ui.control.EditField
        LabelEditField7          matlab.ui.control.Label
        PL_gv_NF                 matlab.ui.control.EditField
        LabelNumericEditField18  matlab.ui.control.Label
        PL_st_NF                 matlab.ui.control.NumericEditField
        GatepauseEditFieldLabel  matlab.ui.control.Label
        PL_gp_NF                 matlab.ui.control.NumericEditField
        PL_par_2_TB              matlab.ui.container.Tab
        LabelNumericEditField19  matlab.ui.control.Label
        PL_freq_NF               matlab.ui.control.NumericEditField
        LabelNumericEditField20  matlab.ui.control.Label
        PL_pause_NF              matlab.ui.control.NumericEditField
        PL_aco_PN                matlab.ui.container.Panel
        PL_aco_TF                matlab.ui.control.EditField
        PL_aco_BT                matlab.ui.control.Button
        PL_stop_BT               matlab.ui.control.StateButton
        PL_start_BT              matlab.ui.control.Button
        PL_LP                    matlab.ui.control.Lamp
        PL_so_BT                 matlab.ui.control.StateButton
        LabelNumericEditField16  matlab.ui.control.Label
        PL_T1_NF                 matlab.ui.control.NumericEditField
        LabelNumericEditField17  matlab.ui.control.Label
        PL_T2_NF                 matlab.ui.control.NumericEditField
    end


    properties (Access = public)
        appMF % Description
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, gui)
            app.appMF = gui;
            tech.amf_pos.bot_lft(app);
            tech.state.loadState(app);
        end

        % Value changed function: PL_so_BT
        function PL_so_BTValueChanged(app, event)
            value = app.PL_so_BT.Value;
            switch value
                case 1
                    try
                        app.PL_so_LP.Color = 'Yellow';
                        app.PL_cur_NF.Editable = 'off';
                        app.PL_st_NF.Editable = 'off';
                        app.PL_gv_NF.Editable = 'off';
                        app.PL_freq_NF.Editable = 'off';
                        app.PL_pause_NF.Editable = 'off';
                        app.PL_gp_NF.Editable = 'off';
                        app.appMF.PL.gpp = app.PL_gp_NF.Value;
                        s = textscan(app.PL_cur_NF.Value,'%f','Delimiter',':');
                        v = s{1};
                        app.appMF.PL.cur = v(1);
                        app.appMF.PL.dI = v(2);
                        app.appMF.PL.st = app.PL_st_NF.Value;
                        app.appMF.PL.gv = str2num(app.PL_gv_NF.Value);
                        app.appMF.PL.freq = app.PL_freq_NF.Value;
                        app.appMF.PL.pause = app.PL_pause_NF.Value;
                        app.PL_start_BT.Enable = 'on';
                        app.PL_so_LP.Color = 'Green';
                    catch ME
                        app.PL_so_LP.Color = 'Red';
                        app.appMF.err(ME);
                    end     
                case 0
                    app.PL_start_BT.Enable = 'off';
                    app.PL_stop_BT.Enable = 'off';
                    app.PL_cur_NF.Editable = 'on';
                    app.PL_st_NF.Editable = 'on';
                    app.PL_gv_NF.Editable = 'on';
                    app.PL_freq_NF.Editable = 'on';
                    app.PL_pause_NF.Editable = 'on';
                    app.PL_gp_NF.Editable = 'on';
                    app.PL_so_LP.Color = 'White';
            end
        end

        % Button pushed function: PL_start_BT
        function PL_start_BTButtonPushed(app, event)
            try
                app.PL_start_BT.Enable = 'off';
                app.PL_stop_BT.Enable = 'on';
                app.PL_LP.Color = 'Yellow';
                cla(app.appMF.W_Ax);
                delete(findall(app.appMF.W_Ax,'Type','Text'));
                %f = figure('Color','w');
                %movegui(f,'west');
                imshow('wip.jpg','Parent',app.appMF.W_Ax);
                mst.PL.pul(app.appMF);
                app.PL_stop_BT.Enable = 'off';
                imshow('nip.jpg','Parent',app.appMF.W_Ax);
                app.PL_aco_PN.Visible = 'on';
                app.PL_LP.Color = 'Green';
            catch ME
                app.PL_LP.Color = 'Red';
                app.appMF.err(ME);
            end
        end

        % Close request function: UI
        function UICloseRequest(app, event)
            app.appMF.MstPulMenu.Enable = 'on';
            tech.state.saveState(app);
            delete(app);
        end

        % Value changed function: PL_stop_BT
        function PL_stop_BTValueChanged(app, event)
            val = app.PL_stop_BT.Value;
            switch val
                case 1
                    warning('mstPulstop:on','Measurements will STOP at the end of current bias step');
                case 0
                    warning('mstPulstop:off','Measurements will NOT STOP at the end of current bias step')
            end
        end

        % Button pushed function: PL_aco_BT
        function PL_aco_BTButtonPushed(app, event)
            app.appMF.JO.joPul.add_com(app.PL_aco_TF.Value,app.appMF.JO.co);
            app.PL_aco_TF.Value = '';
            app.PL_aco_PN.Visible = 'off';
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UI = uifigure;
            app.UI.Name = 'Pulse measurements';
            app.UI.CloseRequestFcn = createCallbackFcn(app, @UICloseRequest, true);
            app.PL_so_LP = uilamp(app.UI);
            app.PL_par_PN = uipanel(app.UI);
            app.PL_par_PN.Title = 'Parameters';
            app.PL_par_TB = uitabgroup(app.PL_par_PN);
            app.PL_par_1_TB = uitab(app.PL_par_TB);
            app.PL_par_1_TB.Title = 'Main';
            app.LabelEditField = uilabel(app.PL_par_1_TB);
            app.LabelEditField.Text = 'Current';
            app.PL_cur_NF = uieditfield(app.PL_par_1_TB, 'text');
            app.PL_cur_NF.Value = '1e-5:2e-9';
            app.LabelEditField7 = uilabel(app.PL_par_1_TB);
            app.LabelEditField7.Text = 'Gate';
            app.PL_gv_NF = uieditfield(app.PL_par_1_TB, 'text');
            app.PL_gv_NF.Value = '0';
            app.LabelNumericEditField18 = uilabel(app.PL_par_1_TB);
            app.LabelNumericEditField18.Text = 'Statistics';
            app.PL_st_NF = uieditfield(app.PL_par_1_TB, 'numeric');
            app.PL_st_NF.Value = 1000;
            app.GatepauseEditFieldLabel = uilabel(app.PL_par_1_TB);
            app.GatepauseEditFieldLabel.Text = 'Gate pause';
            app.PL_gp_NF = uieditfield(app.PL_par_1_TB, 'numeric');
            app.PL_gp_NF.Limits = [0 Inf];
            app.PL_par_2_TB = uitab(app.PL_par_TB);
            app.PL_par_2_TB.Title = 'Pulses';
            app.LabelNumericEditField19 = uilabel(app.PL_par_2_TB);
            app.LabelNumericEditField19.Text = 'Frequency, Hz';
            app.PL_freq_NF = uieditfield(app.PL_par_2_TB, 'numeric');
            app.PL_freq_NF.Value = 5000;
            app.LabelNumericEditField20 = uilabel(app.PL_par_2_TB);
            app.LabelNumericEditField20.Text = 'Pause, Hz';
            app.PL_pause_NF = uieditfield(app.PL_par_2_TB, 'numeric');
            app.PL_pause_NF.Value = 10;
            app.PL_aco_PN = uipanel(app.UI);
            app.PL_aco_PN.Title = 'Comments';
            app.PL_aco_PN.Visible = 'off';
            app.PL_aco_TF = uieditfield(app.PL_aco_PN, 'text');
            app.PL_aco_BT = uibutton(app.PL_aco_PN, 'push');
            app.PL_aco_BT.ButtonPushedFcn = createCallbackFcn(app, @PL_aco_BTButtonPushed, true);
            app.PL_aco_BT.Text = 'Add';
            app.PL_stop_BT = uibutton(app.UI, 'state');
            app.PL_stop_BT.ValueChangedFcn = createCallbackFcn(app, @PL_stop_BTValueChanged, true);
            app.PL_stop_BT.Enable = 'off';
            app.PL_stop_BT.Text = 'Stop';
            app.PL_start_BT = uibutton(app.UI, 'push');
            app.PL_start_BT.ButtonPushedFcn = createCallbackFcn(app, @PL_start_BTButtonPushed, true);
            app.PL_start_BT.Enable = 'off';
            app.PL_start_BT.Text = 'Start';
            app.PL_LP = uilamp(app.UI);
            app.PL_so_BT = uibutton(app.UI, 'state');
            app.PL_so_BT.ValueChangedFcn = createCallbackFcn(app, @PL_so_BTValueChanged, true);
            app.PL_so_BT.Text = 'Set';
            app.LabelNumericEditField16 = uilabel(app.UI);
            app.LabelNumericEditField16.Text = 'T1';
            app.PL_T1_NF = uieditfield(app.UI, 'numeric');
            app.PL_T1_NF.Editable = 'off';
            app.LabelNumericEditField17 = uilabel(app.UI);
            app.LabelNumericEditField17.Text = 'T2';
            app.PL_T2_NF = uieditfield(app.UI, 'numeric');
            app.PL_T2_NF.Editable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = MstPL_UI(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UI)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UI)
        end
    end
end