classdef msttem_UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UI                    matlab.ui.Figure
        Tem_meas_start_BT     matlab.ui.control.StateButton
        Tem_meas_LP           matlab.ui.control.Lamp
        PausesEditFieldLabel  matlab.ui.control.Label
        Tem_meas_pause_NF     matlab.ui.control.NumericEditField
    end


    properties (Access = public)
        appMF % Description
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, gui)
            app.appMF = gui;
            tech.amf_pos.bot_lft(app);
            tech.state.loadState(app);
        end

        % Value changed function: Tem_meas_start_BT
        function Tem_meas_start_BTValueChanged(app, event)
            value = app.Tem_meas_start_BT.Value;
            switch value
                case 1
                    try
                        q = figure;
                        ax = axes(q);
                        set(q,'NumberTitle','off','Name','IV');
                        title('Temperature log');
                        xlabel(ax2,'Time[s]');
                        ylabel(ax2,'T[K]');
                        hold(ax,'on');
                        f = 0; 
                        app.Tem_meas_LP.Color = 'Yellow';
                        app.Tem_meas_start_BT.Text = 'Stop';
                        while app.Tem_meas_start_BT.Value
                            [r,Temp] = app.lgTem.add_log_temp(app.brg);
                            plot(ax,f*app.Tem_meas_pause_NF.Value,Temp,'-o');
                            f = f + 1;
                            pause(app.Tem_meas_pause_NF.Value);
                        end
                        app.Tem_meas_LP.Color = 'Green';
                    catch ME
                        app.Tem_meas_LP.Color = 'Red';
                        app.appMF.err(ME);
                    end
                case 0
                    app.Tem_meas_start_BT.Text = 'Start';
                    hold(ax,'off');
                    app.Tem_meas_LP.Color = 'White';
            end
        end

        % Close request function: UI
        function UICloseRequest(app, event)
            app.appMF.TMenu.Enable = 'on';
            tech.state.saveState(app);
            delete(app)
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UI = uifigure;
            app.UI.Name = 'UI Figure';
            app.UI.CloseRequestFcn = createCallbackFcn(app, @UICloseRequest, true);
            app.Tem_meas_start_BT = uibutton(app.UI, 'state');
            app.Tem_meas_start_BT.ValueChangedFcn = createCallbackFcn(app, @Tem_meas_start_BTValueChanged, true);
            app.Tem_meas_start_BT.Text = 'Start';
            app.Tem_meas_LP = uilamp(app.UI);
            app.PausesEditFieldLabel = uilabel(app.UI);
            app.PausesEditFieldLabel.Text = 'Pause, s';
            app.Tem_meas_pause_NF = uieditfield(app.UI, 'numeric');
        end
    end

    methods (Access = public)

        % Construct app
        function app = msttem_UI(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UI)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UI)
        end
    end
end