classdef Ntf_UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UI                      matlab.ui.Figure
        Test_BT                 matlab.ui.control.Button
        TextEditFieldLabel      matlab.ui.control.Label
        Test_EF                 matlab.ui.control.EditField
        TabGroup                matlab.ui.container.TabGroup
        TelegramTab             matlab.ui.container.Tab
        NtfTlgAct_BT            matlab.ui.control.StateButton
        NtfTlg_LP               matlab.ui.control.Lamp
        ChatIDEditFieldLabel    matlab.ui.control.Label
        NtfTlgChnID_EF          matlab.ui.control.EditField
        BotTokenEditFieldLabel  matlab.ui.control.Label
        NtfTlgToken_EF          matlab.ui.control.EditField
        NtfTlgCrtBot_BT         matlab.ui.control.StateButton
        EmailTab                matlab.ui.container.Tab
        NtfEmlAct_BT            matlab.ui.control.StateButton
        NtfEml_LP               matlab.ui.control.Lamp
        ReceiverLabel           matlab.ui.control.Label
        NtfEml_EF               matlab.ui.control.EditField
    end

    properties (Access = private)
        EmAddr;
        eml;
    end
    properties (Access = public)
        appMF; % Description
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, gui)
            app.appMF = gui;
            tech.state.loadState(app);
            tech.amf_pos.rgt_top(app);
        end

        % Value changed function: NtfTlgAct_BT
        function NtfTlgAct_BTValueChanged(app, event)
            val = app.NtfTlgAct_BT.Value;
            switch val
                case 1
                    try
                        app.NtfTlg_LP.Color = 'Yellow';
                        app.appMF.TlgBot.trg = 1;
                        app.NtfTlg_LP.Color = 'Green';
                    catch ME
                        app.appMF.err(ME);
                    end
                case 0
                    app.appMF.TlgBot.trg = 0;
                    app.NtfTlg_LP.Color = 'Blue';
            end
        end

        % Value changed function: NtfEmlAct_BT
        function NtfEmlAct_BTValueChanged(app, event)
            val = app.NtfEmlAct_BT.Value;
            switch val
                case 1
                    try
                        app.NtfEml_LP.Color = 'Yellow';
                        app.appMF.EmlBot = instruments.emlbot(app.NtfEml_EF.Value);
                        app.appMF.EmlBot.trg = 1;
                        app.NtfEml_EF.Editable = 'off';
                        app.NtfEml_LP.Color = 'Green';
                    catch ME
                        app.appMF.err(ME);
                    end
                case 0
                    app.appMF.EmlTrg = 0;
                    app.NtfEml_EF.Editable = 'on';
                    app.NtfEml_LP.Color = 'Blue';
            end
        end

        % Close request function: UI
        function UICloseRequest(app, event)
            tech.state.saveState(app);
            app.appMF.NtfMenu.Enable = 'on';
            delete(app);
        end

        % Button pushed function: Test_BT
        function Test_BTButtonPushed(app, event)
            app.appMF.TlgBot.msg2chn(app.Test_EF.Value);
        end

        % Value changed function: NtfTlgCrtBot_BT
        function NtfTlgCrtBot_BTButtonPushed(app, event)
            val = app.NtfTlgCrtBot_BT.Value;
            switch val
                case 1
                    app.appMF.TlgBot = instruments.tlgbot(app.NtfTlgToken_EF.Value);
                    app.appMF.TlgBot.ChanID = app.NtfTlgChnID_EF.Value;
                    app.NtfTlgToken_EF.Editable = 'off';
                    app.NtfTlgChnID_EF.Editable = 'off';
                case 0
                    delete(app.appMF.TlgBot);
                    app.NtfTlgToken_EF.Editable = 'on';
                    app.NtfTlgChnID_EF.Editable = 'on';
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UI = uifigure;
            app.UI.Name = 'UI Figure';
            app.UI.CloseRequestFcn = createCallbackFcn(app, @UICloseRequest, true);
            app.Test_BT = uibutton(app.UI, 'push');
            app.Test_BT.ButtonPushedFcn = createCallbackFcn(app, @Test_BTButtonPushed, true);
            app.Test_BT.Text = 'Test';
            app.TextEditFieldLabel = uilabel(app.UI);
            app.TextEditFieldLabel.Text = 'Text';
            app.Test_EF = uieditfield(app.UI, 'text');
            app.TabGroup = uitabgroup(app.UI);
            app.TelegramTab = uitab(app.TabGroup);
            app.TelegramTab.Title = 'Telegram';
            app.NtfTlgAct_BT = uibutton(app.TelegramTab, 'state');
            app.NtfTlgAct_BT.ValueChangedFcn = createCallbackFcn(app, @NtfTlgAct_BTValueChanged, true);
            app.NtfTlgAct_BT.Text = 'Activate';
            app.NtfTlg_LP = uilamp(app.TelegramTab);
            app.ChatIDEditFieldLabel = uilabel(app.TelegramTab);
            app.ChatIDEditFieldLabel.Text = 'Chat ID';
            app.NtfTlgChnID_EF = uieditfield(app.TelegramTab, 'text');
            app.NtfTlgChnID_EF.Value = '156097768';
            app.BotTokenEditFieldLabel = uilabel(app.TelegramTab);
            app.BotTokenEditFieldLabel.Text = 'Bot Token';
            app.NtfTlgToken_EF = uieditfield(app.TelegramTab, 'text');
            app.NtfTlgToken_EF.Value = '549951830:AAFrx7jVHNOYv2Yv7R24bmsegVx1mZHrqBs';
            app.NtfTlgCrtBot_BT = uibutton(app.TelegramTab, 'state');
            app.NtfTlgCrtBot_BT.ValueChangedFcn = createCallbackFcn(app, @NtfTlgCrtBot_BTButtonPushed, true);
            app.NtfTlgCrtBot_BT.Text = 'Create';
            app.EmailTab = uitab(app.TabGroup);
            app.EmailTab.Title = 'Email';
            app.NtfEmlAct_BT = uibutton(app.EmailTab, 'state');
            app.NtfEmlAct_BT.ValueChangedFcn = createCallbackFcn(app, @NtfEmlAct_BTValueChanged, true);
            app.NtfEmlAct_BT.Text = 'Activate';
            app.NtfEml_LP = uilamp(app.EmailTab);
            app.ReceiverLabel = uilabel(app.EmailTab);
            app.ReceiverLabel.Text = 'Receiver';
            app.NtfEml_EF = uieditfield(app.EmailTab, 'text');
            app.NtfEml_EF.Value = 'ak.nagaev@gmail.com';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Ntf_UI(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UI)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UI)
        end
    end
end