classdef SetAVS_UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UI                        matlab.ui.Figure
        AVS_PN                    matlab.ui.container.Panel
        AVS_Get_BT                matlab.ui.control.Button
        AVS_Set_BT                matlab.ui.control.Button
        AVS_Get_Set_LP            matlab.ui.control.Lamp
        ChannelEditFieldLabel     matlab.ui.control.Label
        AVS_Chn_EF                matlab.ui.control.NumericEditField
        RangeEditFieldLabel       matlab.ui.control.Label
        AVS_Rng_EF                matlab.ui.control.NumericEditField
        ExcitationEditFieldLabel  matlab.ui.control.Label
        AVS_Exc_EF                matlab.ui.control.NumericEditField
        AddressEditFieldLabel     matlab.ui.control.Label
        AVS_Create_EF             matlab.ui.control.EditField
        AVS_Create_LP             matlab.ui.control.Lamp
        AVS_Create_CB             matlab.ui.control.CheckBox
        AVS_Create_SW             matlab.ui.control.Switch
        AVS_Create_BT             matlab.ui.control.StateButton
    end


    properties (Access = public)
        appMF; % Description
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, gui)
            app.appMF = gui;
            tech.state.loadState(app);
            tech.amf_pos.lft_top(app);
        end

        % Button pushed function: AVS_Get_BT
        function AVS_Get_BTPushed(app, event)
            [chn,rng,exc] = app.appMF.brg.brg_get;
            app.AVS_Chn_EF.Value = chn;
            app.AVS_Rng_EF.Value = rng;
            app.AVS_Exc_EF.Value = exc;
        end

        % Value changed function: AVS_Create_BT
        function AVS_Create_BTValueChanged(app, event)
            value = app.AVS_Create_BT.Value;
            switch value
                case 1
                    try
                    app.AVS_Create_LP.Color = 'Yellow';
                    app.AVS_Create_EF.Editable = 'off';
                    app.AVS_Create_CB.Enable = 'off';
                    app.AVS_Create_SW.Enable = 'off';
                    if app.AVS_Create_CB.Value
                        %app.appMF.brg = instruments.resbrg(app.AVS_Create_EF.Value,'AVS');
                        app.appMF.DV.brg.addr = app.AVS_Create_EF.Value;
                        app.appMF.DV.brg.model = app.AVS_Create_SW.Value;
                    end
                    %app.AVS_Set_BT.Enable = 'on';
                    %app.AVS_Get_BT.Enable = 'on';
                    app.AVS_Create_LP.Color = 'Green';
                    catch ME
                        app.appMF.err(ME);
                    end
                case 0
                    app.AVS_Create_LP.Color = 'Yellow';
                    app.AVS_Create_EF.Editable = 'on';
                    app.AVS_Create_CB.Enable = 'on';
                    app.AVS_Create_SW.Enable = 'on';
                    %app.AVS_Set_BT.Enable = 'off';
                    %app.AVS_Get_BT.Enable = 'off';
                    %delete(app.appMF.brg);
                    app.AVS_Create_LP.Color = 'White';
            end
        end

        % Button pushed function: AVS_Set_BT
        function AVS_Set_BTButtonPushed(app, event)
            chn = app.AVS_Chn_EF.Value ;
            rng = app.AVS_Rng_EF.Value;
            exc = app.AVS_Exc_EF.Value;
            app.appMF.brg.brg_set(chn,rng,exc)
        end

        % Close request function: UI
        function UICloseRequest(app, event)
            app.appMF.SetAVSMenu.Enable = 'on';
            tech.state.saveState(app);
            delete(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UI = uifigure;
            app.UI.Name = 'Set AVS';
            app.UI.CloseRequestFcn = createCallbackFcn(app, @UICloseRequest, true);
            app.AVS_PN = uipanel(app.UI);
            app.AVS_PN.Title = 'AVS-47/AVS-48';
            app.AVS_Get_BT = uibutton(app.AVS_PN, 'push');
            app.AVS_Get_BT.ButtonPushedFcn = createCallbackFcn(app, @AVS_Get_BTPushed, true);
            app.AVS_Get_BT.Enable = 'off';
            app.AVS_Get_BT.Text = 'Get';
            app.AVS_Set_BT = uibutton(app.AVS_PN, 'push');
            app.AVS_Set_BT.ButtonPushedFcn = createCallbackFcn(app, @AVS_Set_BTButtonPushed, true);
            app.AVS_Set_BT.Enable = 'off';
            app.AVS_Set_BT.Text = 'Set';
            app.AVS_Get_Set_LP = uilamp(app.AVS_PN);
            app.ChannelEditFieldLabel = uilabel(app.AVS_PN);
            app.ChannelEditFieldLabel.Text = 'Channel';
            app.AVS_Chn_EF = uieditfield(app.AVS_PN, 'numeric');
            app.RangeEditFieldLabel = uilabel(app.AVS_PN);
            app.RangeEditFieldLabel.Text = 'Range';
            app.AVS_Rng_EF = uieditfield(app.AVS_PN, 'numeric');
            app.ExcitationEditFieldLabel = uilabel(app.AVS_PN);
            app.ExcitationEditFieldLabel.Text = 'Excitation';
            app.AVS_Exc_EF = uieditfield(app.AVS_PN, 'numeric');
            app.AddressEditFieldLabel = uilabel(app.AVS_PN);
            app.AddressEditFieldLabel.Text = 'Address';
            app.AVS_Create_EF = uieditfield(app.AVS_PN, 'text');
            app.AVS_Create_EF.Value = '20';
            app.AVS_Create_LP = uilamp(app.AVS_PN);
            app.AVS_Create_CB = uicheckbox(app.AVS_PN);
            app.AVS_Create_CB.Text = '';
            app.AVS_Create_CB.Value = true;
            app.AVS_Create_SW = uiswitch(app.AVS_PN, 'slider');
            app.AVS_Create_SW.Items = {'AVS-47', 'AVS-48'};
            app.AVS_Create_SW.Value = 'AVS-48';
            app.AVS_Create_BT = uibutton(app.AVS_PN, 'state');
            app.AVS_Create_BT.ValueChangedFcn = createCallbackFcn(app, @AVS_Create_BTValueChanged, true);
            app.AVS_Create_BT.Text = 'Create';
        end
    end

    methods (Access = public)

        % Construct app
        function app = SetAVS_UI(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UI)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UI)
        end
    end
end