classdef SetDAQ_UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UI                      matlab.ui.Figure
        DAQ_add_PN              matlab.ui.container.Panel
        DAQ_add_CB              matlab.ui.control.CheckBox
        RateEditFieldLabel      matlab.ui.control.Label
        DAQ_srt_NF              matlab.ui.control.NumericEditField
        ChannelsEditFieldLabel  matlab.ui.control.Label
        DAQ_chn_NF              matlab.ui.control.EditField
        RangeEditFieldLabel     matlab.ui.control.Label
        DAQ_rng_NF              matlab.ui.control.EditField
        DAQ_Set_BT              matlab.ui.control.StateButton
        DAQ_Set_LP              matlab.ui.control.Lamp
    end


    properties (Access = public)
        appMF; % Description
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, gui)
            app.appMF = gui;
            tech.state.loadState(app);
            tech.amf_pos.lft_top(app);
        end

        % Value changed function: DAQ_Set_BT
        function DAQ_Set_BTValueChanged(app, event)
            val = app.DAQ_Set_BT.Value;
            switch val
                case 1
                    try
                        app.DAQ_Set_LP.Color = 'Yellow';
                        if app.DAQ_add_CB.Value
                            app.appMF.DV.nic.addr = 'USB-6251';
                            app.appMF.DV.nic.ch_id = str2double(app.DAQ_chn_NF.Value);
                            app.appMF.DV.nic.ch_type = 'Voltage';
                            %app.appMF.nic.ch = app.appMF.nic.ses.addAnalogInputChannel(app.appMF.nic.dev.ID, app.appMF.nic.ch_id, app.appMF.nic.ch_type);
                            app.appMF.DV.nic.ses.Rate = app.DAQ_srt_NF.Value;
                       	    app.appMF.DV.ch.Range = str2num(app.DAQ_rng_NF.Value);
                        end
                        app.DAQ_add_CB.Enable = 'off';
                        app.DAQ_srt_NF.Editable = 'off';
                        app.DAQ_chn_NF.Editable = 'off';
                        app.DAQ_rng_NF.Editable = 'off';
                        app.DAQ_Set_LP.Color = 'Green';
                    catch ME
                        app.appMF.err(ME);
                    end
                case 0
                    app.DAQ_Set_LP.Color = 'Yellow';
                    app.DAQ_add_CB.Enable = 'on';
                    app.DAQ_srt_NF.Editable = 'on';
                    app.DAQ_chn_NF.Editable = 'on';
                    app.DAQ_rng_NF.Editable = 'on';
                    clear(app.appMF.nic);
                    app.DAQ_Set_LP.Color = 'White';
            end
        end

        % Close request function: UI
        function UICloseRequest(app, event)
            app.appMF.SetDAQMenu.Enable = 'on';
            tech.state.saveState(app);
            delete(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UI = uifigure;
            app.UI.Name = 'UI Figure';
            app.UI.CloseRequestFcn = createCallbackFcn(app, @UICloseRequest, true);
            app.DAQ_add_PN = uipanel(app.UI);
            app.DAQ_add_PN.Title = 'NI-6251';
            app.DAQ_add_CB = uicheckbox(app.DAQ_add_PN);
            app.DAQ_add_CB.Text = 'NI-6251';
            app.DAQ_add_CB.Value = true;
            app.RateEditFieldLabel = uilabel(app.DAQ_add_PN);
            app.RateEditFieldLabel.Text = 'Rate';
            app.DAQ_srt_NF = uieditfield(app.DAQ_add_PN, 'numeric');
            app.DAQ_srt_NF.Value = 1000000;
            app.ChannelsEditFieldLabel = uilabel(app.DAQ_add_PN);
            app.ChannelsEditFieldLabel.Text = 'Channels';
            app.DAQ_chn_NF = uieditfield(app.DAQ_add_PN, 'text');
            app.DAQ_chn_NF.Value = '0';
            app.RangeEditFieldLabel = uilabel(app.DAQ_add_PN);
            app.RangeEditFieldLabel.Text = 'Range';
            app.DAQ_rng_NF = uieditfield(app.DAQ_add_PN, 'text');
            app.DAQ_rng_NF.Value = '-10,10';
            app.DAQ_Set_BT = uibutton(app.UI, 'state');
            app.DAQ_Set_BT.ValueChangedFcn = createCallbackFcn(app, @DAQ_Set_BTValueChanged, true);
            app.DAQ_Set_BT.Text = 'Set';
            app.DAQ_Set_LP = uilamp(app.UI);
        end
    end

    methods (Access = public)

        % Construct app
        function app = SetDAQ_UI(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UI)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UI)
        end
    end
end