classdef SetDev_UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UI                    matlab.ui.Figure
        dev_add               matlab.ui.container.Panel
        dev_add_LP            matlab.ui.control.Lamp
        dev_add_BT            matlab.ui.control.StateButton
        WFm_efl               matlab.ui.control.Label
        dev_add_wgm_NF        matlab.ui.control.EditField
        WFgateEditFieldLabel  matlab.ui.control.Label
        dev_add_wgg_NF        matlab.ui.control.EditField
        DCPSEditFieldLabel    matlab.ui.control.Label
        dev_add_psg_NF        matlab.ui.control.EditField
        Mult2Label            matlab.ui.control.Label
        dev_add_mu2_NF        matlab.ui.control.EditField
        Mult1EditFieldLabel   matlab.ui.control.Label
        dev_add_mu1_NF        matlab.ui.control.EditField
        dev_add_wgm_CB        matlab.ui.control.CheckBox
        dev_add_wgg_CB        matlab.ui.control.CheckBox
        dev_add_psg_CB        matlab.ui.control.CheckBox
        dev_add_mu1_CB        matlab.ui.control.CheckBox
        dev_add_mu2_CB        matlab.ui.control.CheckBox
        dev_add_lia_CB        matlab.ui.control.CheckBox
        LIALabel              matlab.ui.control.Label
        dev_add_lia_NF        matlab.ui.control.EditField
    end

    properties (Access = public)
        appMF; % App mainframe     
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, gui)
            app.appMF = gui;
            tech.state.loadState(app);
            tech.amf_pos.lft_top(app);
        end

        % Value changed function: dev_add_BT
        function dev_add_BT_BP(app, event)
            val = app.dev_add_BT.Value;
            switch val
                case 1
                    try                        
                        app.dev_add_LP.Color = 'Yellow';
                        if app.dev_add_wgm_CB.Value
                            %app.appMF.wgm = instruments.wfgen(app.dev_add_wgm_NF.Value,'Main generator');
                            app.appMF.DV.wgm = app.dev_add_wgm_NF.Value;
                        end
                        if app.dev_add_wgg_CB.Value
                            %app.appMF.wgg = instruments.wfgen(app.dev_add_wgg_NF.Value,'Gate generator');
                            app.appMF.DV.wgg = app.dev_add_wgg_NF.Value;
                        end
                        if app.dev_add_psg_CB.Value
                            %app.appMF.psg = instruments.dcpws(app.dev_add_psg_NF.Value,'Gate generator',1);
                            app.appMF.DV.psg = app.dev_add_psg_NF.Value;
                        end
                        if app.dev_add_mu1_CB.Value
                            %app.appMF.mu1 = instruments.mult(app.dev_add_mu1_NF.Value,'Voltage Multimeter');
                            app.appMF.DV.mu1 = app.dev_add_mu1_NF.Value;
                        end
                        if app.dev_add_mu2_CB.Value
                            %app.appMF.mu2 = instruments.mult(app.dev_add_mu2_NF.Value,'Current Multimeter');
                            app.appMF.DV.mu2 = app.dev_add_mu2_NF.Value;
                        end
                        if app.dev_add_lia_CB.Value
                            %app.appMF.lia = instruments.liao(app.dev_add_lia_NF.Value,'LIA');
                            app.appMF.DV.lia = app.dev_add_lia_NF.Value;
                        end
                        app.dev_add_wgm_NF.Editable = 'off';
                        app.dev_add_mu1_NF.Editable = 'off';
                        app.dev_add_mu2_NF.Editable = 'off';
                        app.dev_add_psg_NF.Editable = 'off';
                        app.dev_add_wgg_NF.Editable = 'off';
                        app.dev_add_lia_NF.Editable = 'off';
                        app.dev_add_wgg_CB.Enable = 'off';
                        app.dev_add_mu1_CB.Enable = 'off';
                        app.dev_add_mu2_CB.Enable = 'off';
                        app.dev_add_psg_CB.Enable = 'off';
                        app.dev_add_wgm_CB.Enable = 'off';
                        app.dev_add_lia_CB.Enable = 'off';
                        app.dev_add_LP.Color = 'Green';
                        app.appMF.OptionsMenu.Enable = 'on';
                    catch ME
                        app.dev_add_LP.Color = 'Red';
                        app.appMF.err(ME);
                    end
                case 0
                    try
                    instrreset;
                    %clear app.appMF.wgm app.appMF.wgg app.appMF.psg app.appMF.mt1 app.appMF.mt2 app.appMF.b app.appMF.l app.appMF.ni
                    app.dev_add_wgm_NF.Editable = 'on';
                    app.dev_add_mu1_NF.Editable = 'on';
                    app.dev_add_mu2_NF.Editable = 'on';
                    app.dev_add_psg_NF.Editable = 'on';
                    app.dev_add_wgg_NF.Editable = 'on';
                    app.dev_add_lia_NF.Editable = 'on';
                    app.dev_add_wgg_CB.Enable = 'on';
                    app.dev_add_mu1_CB.Enable = 'on';
                    app.dev_add_mu2_CB.Enable = 'on';
                    app.dev_add_psg_CB.Enable = 'on';
                    app.dev_add_wgm_CB.Enable = 'on';
                    app.dev_add_lia_CB.Enable = 'on';
                    app.dev_add_LP.Color = 'Blue';
                    catch ME
                        app.dev_add_LP.Color = 'Red';
                        app.appMF.err(ME);
                    end
            end
        end

        % Close request function: UI
        function UICloseRequest(app, event)
            app.appMF.SetDevMenu.Enable = 'on';
            tech.state.saveState(app);
            delete(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UI = uifigure;
            app.UI.Name = 'Devices';
            app.UI.CloseRequestFcn = createCallbackFcn(app, @UICloseRequest, true);
            app.dev_add = uipanel(app.UI);
            app.dev_add.Title = 'GPIB address';
            app.dev_add_LP = uilamp(app.dev_add);
            app.dev_add_BT = uibutton(app.dev_add, 'state');
            app.dev_add_BT.ValueChangedFcn = createCallbackFcn(app, @dev_add_BT_BP, true);
            app.dev_add_BT.Text = 'Set';
            app.WFm_efl = uilabel(app.dev_add);
            app.WFm_efl.Text = 'WF gen';
            app.dev_add_wgm_NF = uieditfield(app.dev_add, 'text');
            app.dev_add_wgm_NF.Value = '12';
            app.WFgateEditFieldLabel = uilabel(app.dev_add);
            app.WFgateEditFieldLabel.Text = 'WF gate';
            app.dev_add_wgg_NF = uieditfield(app.dev_add, 'text');
            app.dev_add_wgg_NF.Value = '13';
            app.DCPSEditFieldLabel = uilabel(app.dev_add);
            app.DCPSEditFieldLabel.Text = 'DC PS';
            app.dev_add_psg_NF = uieditfield(app.dev_add, 'text');
            app.dev_add_psg_NF.Value = '5';
            app.Mult2Label = uilabel(app.dev_add);
            app.Mult2Label.Text = 'Mult 2';
            app.dev_add_mu2_NF = uieditfield(app.dev_add, 'text');
            app.dev_add_mu2_NF.Value = '11';
            app.Mult1EditFieldLabel = uilabel(app.dev_add);
            app.Mult1EditFieldLabel.Text = 'Mult 1';
            app.dev_add_mu1_NF = uieditfield(app.dev_add, 'text');
            app.dev_add_mu1_NF.Value = '10';
            app.dev_add_wgm_CB = uicheckbox(app.dev_add);
            app.dev_add_wgm_CB.Text = '';
            app.dev_add_wgm_CB.Value = true;
            app.dev_add_wgg_CB = uicheckbox(app.dev_add);
            app.dev_add_wgg_CB.Text = '';
            app.dev_add_wgg_CB.Value = true;
            app.dev_add_psg_CB = uicheckbox(app.dev_add);
            app.dev_add_psg_CB.Text = '';
            app.dev_add_mu1_CB = uicheckbox(app.dev_add);
            app.dev_add_mu1_CB.Text = '';
            app.dev_add_mu1_CB.Value = true;
            app.dev_add_mu2_CB = uicheckbox(app.dev_add);
            app.dev_add_mu2_CB.Text = '';
            app.dev_add_lia_CB = uicheckbox(app.dev_add);
            app.dev_add_lia_CB.Text = '';
            app.LIALabel = uilabel(app.dev_add);
            app.LIALabel.Text = 'LIA';
            app.dev_add_lia_NF = uieditfield(app.dev_add, 'text');
            app.dev_add_lia_NF.Value = '14';
        end
    end

    methods (Access = public)

        % Construct app
        function app = SetDev_UI(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UI)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UI)
        end
    end
end