classdef SetOpt_UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UI                      matlab.ui.Figure
        dev_opt_PN              matlab.ui.container.Panel
        dev_opt_gg_RG           matlab.ui.container.ButtonGroup
        NG_RB                   matlab.ui.control.RadioButton
        GW_RB                   matlab.ui.control.RadioButton
        GP_RB                   matlab.ui.control.RadioButton
        dev_opt_gn_PN           matlab.ui.container.Panel
        LabelNumericEditField8  matlab.ui.control.Label
        dev_opt_gnv_NF          matlab.ui.control.NumericEditField
        LabelNumericEditField9  matlab.ui.control.Label
        dev_opt_gnc_NF          matlab.ui.control.NumericEditField
        dev_opt_LP              matlab.ui.control.Lamp
        dev_opt_bre_PN          matlab.ui.container.Panel
        dev_opt_bre_NF          matlab.ui.control.NumericEditField
        opt_PL_trg_CB           matlab.ui.control.CheckBox
        dev_opt_swc_PN          matlab.ui.container.Panel
        dev_opt_swc_NF          matlab.ui.control.NumericEditField
        dev_opt_BT              matlab.ui.control.StateButton
        dev_opt_gss_PN          matlab.ui.container.Panel
        dev_opt_gss_NF          matlab.ui.control.NumericEditField
        Testmode_CB             matlab.ui.control.CheckBox
        Tsensor_CB              matlab.ui.control.CheckBox
        IVs_bt_RG               matlab.ui.container.ButtonGroup
        IVs_bt_vol_RB           matlab.ui.control.RadioButton
        IVs_bt_cur_RB           matlab.ui.control.RadioButton
    end


    properties (Access = public)
        appMF; % Description
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, gui)
            app.appMF = gui;
            tech.amf_pos.lft_bot(app);
            tech.state.loadState(app);
        end

        % Close request function: UI
        function UICloseRequest(app, event)
            app.appMF.OptionsMenu.Enable = 'on';
            tech.state.saveState(app);
            delete(app);
        end

        % Value changed function: dev_opt_BT
        function dev_opt_BTValueChanged(app, event)
            val = app.dev_opt_BT.Value;
            switch val
                case 1
                try
                    app.dev_opt_LP.Color = 'Yellow';
                    app.appMF.OP.bias_res = app.dev_opt_bre_NF.Value;
                    app.appMF.OP.gnv = app.dev_opt_gnv_NF.Value;
                    app.appMF.OP.gnc = app.dev_opt_gnc_NF.Value;
                    app.appMF.OP.sw = app.dev_opt_swc_NF.Value;
                    app.appMF.OP.gss = app.dev_opt_gss_NF.Value;
                    switch app.opt_PL_trg_CB.Value
                        case 0
                            app.appMF.OP.tc = 'n';
                        case 1
                            app.appMF.OP.tc = 'y';
                    end
                    switch app.Testmode_CB.Value
                        case 1
                            app.appMF.OP.ts = 'y';
                        case 0
                            app.appMF.OP.ts = 'n';
                    end
                    switch app.Tsensor_CB.Value
                        case 1
                            app.appMF.DV.brg.mode = 1;
                        case 0
                            app.appMF.DV.brg.mode = 0;
                    end
                    switch app.dev_opt_gg_RG.SelectedObject.Text
                        case 'None'
                            app.appMF.OP.gc = 0;
                        case 'Gate gen'
                            app.appMF.OP.gc = 1;
                        case 'DC PS'
                            app.appMF.OP.gc = 2;
                    end
                    switch app.IVs_bt_RG.SelectedObject.Text
                            case 'Voltage'
                                app.appMF.OP.ivb = 'vol';
                            case 'Current'
                                app.appMF.OP.ivb = 'cur';
                    end
                    app.dev_opt_gss_NF.Editable = 'off';
                    app.dev_opt_gnv_NF.Editable = 'off';
                    app.dev_opt_gnc_NF.Editable = 'off';
                    app.dev_opt_bre_NF.Editable = 'off';
                    app.dev_opt_swc_NF.Editable = 'off';
                    app.opt_PL_trg_CB.Enable = 'off';
                    app.IVs_bt_vol_RB.Enable = 'off';
                    app.IVs_bt_cur_RB.Enable = 'off';
                    app.NG_RB.Enable = 'off';
                    app.GW_RB.Enable = 'off';
                    app.GP_RB.Enable = 'off';
                    app.Testmode_CB.Enable = 'off';
                    app.Tsensor_CB.Enable = 'off';
                    app.dev_opt_LP.Color = 'Green';
                    app.appMF.JournalMenu.Enable = 'on';
                catch ME
                    app.dev_opt_LP.Color = 'Red';
                    app.appMF.err(ME);
                end
                case 0
                    app.dev_opt_gnv_NF.Editable = 'on';
                    app.dev_opt_gss_NF.Editable = 'on';
                    app.dev_opt_gnc_NF.Editable = 'on';
                    app.dev_opt_bre_NF.Editable = 'on';
                    app.dev_opt_swc_NF.Editable = 'on';
                    app.opt_PL_trg_CB.Enable = 'on';
                    app.IVs_bt_vol_RB.Enable = 'on';
                    app.IVs_bt_cur_RB.Enable = 'on';
                    app.NG_RB.Enable = 'on';
                    app.GW_RB.Enable = 'on';
                    app.GP_RB.Enable = 'on';
                    app.Testmode_CB.Enable = 'on';
                    app.Tsensor_CB.Enable = 'on';
                    app.dev_opt_LP.Color = 'Blue';
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UI = uifigure;
            app.UI.Name = 'Options';
            app.UI.CloseRequestFcn = createCallbackFcn(app, @UICloseRequest, true);
            app.dev_opt_PN = uipanel(app.UI);
            app.dev_opt_PN.Title = 'Options';
            app.dev_opt_gg_RG = uibuttongroup(app.dev_opt_PN);
            app.dev_opt_gg_RG.Title = 'Gate generator';
            app.NG_RB = uiradiobutton(app.dev_opt_gg_RG);
            app.NG_RB.Text = 'None';
            app.NG_RB.Value = true;
            app.GW_RB = uiradiobutton(app.dev_opt_gg_RG);
            app.GW_RB.Text = 'Gate gen';
            app.GP_RB = uiradiobutton(app.dev_opt_gg_RG);
            app.GP_RB.Text = 'DC PS';
            app.dev_opt_gn_PN = uipanel(app.dev_opt_PN);
            app.dev_opt_gn_PN.Title = 'Amplifier gain';
            app.LabelNumericEditField8 = uilabel(app.dev_opt_gn_PN);
            app.LabelNumericEditField8.Text = 'Voltage gain';
            app.dev_opt_gnv_NF = uieditfield(app.dev_opt_gn_PN, 'numeric');
            app.dev_opt_gnv_NF.Limits = [0 50000];
            app.dev_opt_gnv_NF.Value = 100;
            app.LabelNumericEditField9 = uilabel(app.dev_opt_gn_PN);
            app.LabelNumericEditField9.Text = 'Current gain';
            app.dev_opt_gnc_NF = uieditfield(app.dev_opt_gn_PN, 'numeric');
            app.dev_opt_gnc_NF.Limits = [0 100000000];
            app.dev_opt_gnc_NF.ValueDisplayFormat = '%1.0e';
            app.dev_opt_gnc_NF.Value = 10000;
            app.dev_opt_LP = uilamp(app.dev_opt_PN);
            app.dev_opt_bre_PN = uipanel(app.dev_opt_PN);
            app.dev_opt_bre_PN.Title = 'Bias resistance';
            app.dev_opt_bre_NF = uieditfield(app.dev_opt_bre_PN, 'numeric');
            app.dev_opt_bre_NF.ValueDisplayFormat = '%1.0e';
            app.dev_opt_bre_NF.Value = 1000000;
            app.opt_PL_trg_CB = uicheckbox(app.dev_opt_PN);
            app.opt_PL_trg_CB.Text = 'Triggers';
            app.opt_PL_trg_CB.Value = true;
            app.dev_opt_swc_PN = uipanel(app.dev_opt_PN);
            app.dev_opt_swc_PN.Title = 'Switching criteria';
            app.dev_opt_swc_NF = uieditfield(app.dev_opt_swc_PN, 'numeric');
            app.dev_opt_swc_NF.ValueDisplayFormat = '%1.0e';
            app.dev_opt_swc_NF.Value = 1e-05;
            app.dev_opt_BT = uibutton(app.dev_opt_PN, 'state');
            app.dev_opt_BT.ValueChangedFcn = createCallbackFcn(app, @dev_opt_BTValueChanged, true);
            app.dev_opt_BT.Text = 'Set';
            app.dev_opt_gss_PN = uipanel(app.dev_opt_PN);
            app.dev_opt_gss_PN.Title = 'Gate sweep speed';
            app.dev_opt_gss_NF = uieditfield(app.dev_opt_gss_PN, 'numeric');
            app.dev_opt_gss_NF.Value = 0.05;
            app.Testmode_CB = uicheckbox(app.dev_opt_PN);
            app.Testmode_CB.Text = 'Test mode';
            app.Tsensor_CB = uicheckbox(app.dev_opt_PN);
            app.Tsensor_CB.Text = 'T sensor';
            app.Tsensor_CB.Value = true;
            app.IVs_bt_RG = uibuttongroup(app.dev_opt_PN);
            app.IVs_bt_RG.Title = 'Bias type';
            app.IVs_bt_vol_RB = uiradiobutton(app.IVs_bt_RG);
            app.IVs_bt_vol_RB.Text = 'Voltage';
            app.IVs_bt_vol_RB.Value = true;
            app.IVs_bt_cur_RB = uiradiobutton(app.IVs_bt_RG);
            app.IVs_bt_cur_RB.Text = 'Current';
        end
    end

    methods (Access = public)

        % Construct app
        function app = SetOpt_UI(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UI)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UI)
        end
    end
end