classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UI                matlab.ui.Figure
        DevicesMenu       matlab.ui.container.Menu
        SetMenu           matlab.ui.container.Menu
        SetDevMenu        matlab.ui.container.Menu
        SetAVSMenu        matlab.ui.container.Menu
        SetDAQMenu        matlab.ui.container.Menu
        OptionsMenu       matlab.ui.container.Menu
        ResetMenu         matlab.ui.container.Menu
        JournalMenu       matlab.ui.container.Menu
        SetupMenu         matlab.ui.container.Menu
        AddNoteMenu       matlab.ui.container.Menu
        BondingMenu       matlab.ui.container.Menu
        MeasurementsMenu  matlab.ui.container.Menu
        MstIVsMenu        matlab.ui.container.Menu
        MstPulMenu        matlab.ui.container.Menu
        MstTemMenu        matlab.ui.container.Menu
        AnsMenu           matlab.ui.container.Menu
        AnsIVsMenu        matlab.ui.container.Menu
        AnsPulMenu        matlab.ui.container.Menu
        AnsResMenu        matlab.ui.container.Menu
        MiscMenu          matlab.ui.container.Menu
        SaveSetMenu       matlab.ui.container.Menu
        LoadSetMenu       matlab.ui.container.Menu
        NtfMenu           matlab.ui.container.Menu
        W_Ax              matlab.ui.control.UIAxes
    end

    properties (Access = public)
        %% Modules
        Modules = struct;
        %% State
        States = struct;
        %% Devices
        DV = struct;
        %% Journals
        JO = struct;
        %% Options
        OP = struct;
        %% IV properties
        IV = struct;
        %% Pulse properties
        PL = struct;
        %% NI card listener axes
        AX = struct;
        fv;
        fvax1;
        fvax2;
        fvax3;
    end
    properties (Access = public)
        AZ = struct;
        %% Analysis
        i;
        fnd;
        lda;
        T;
        G;
        %%
        fndp;
        ldap;
        Tp;
        Gp;
    end
    properties (Access = public)
        TlgBot = instruments.tlgbot(0);
        EmlBot = instruments.emlbot;
    end
    methods (Access = public)
    
        function err(app,ME)
            tech.err.err_rep(app,ME);
            rethrow(ME);
        end
        
        function notify(app,s)
            try
                fprintf('%s\n',s);
                app.TlgBot.msg2chn(s)
                app.EmlBot.snd2eml(s)
            catch ME
                rethrow(ME);
            end
        end
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            if(~isdeployed)
                cd(fileparts(which(mfilename)));
            end
            addpath('Pics');
            warning off all;    
            tech.warn;
            imshow('nip.jpg','Parent',app.W_Ax);
            app.W_Ax.XLabel.String = '';
            app.W_Ax.YLabel.String = '';
            app.W_Ax.Title.String = 'Mainframe loaded';
        end

        % Menu selected function: SetDevMenu
        function SetDevMenuSelected(app, event)
            app.SetDevMenu.Enable = 'off';
            app.Modules.SetDev_UI = set.SetDev_UI(app);
        end

        % Close request function: UI
        function UICloseRequest(app, event)
            ButtonName = questdlg('Close window?', ...
                'Are you sure?', ...
                'Yes','No','No');
            switch ButtonName
                case 'Yes'
                    fclose('all');
                    instrreset;
                    delete(app);
                case 'No'
                    return
            end
        end

        % Menu selected function: OptionsMenu
        function OptionsMenuSelected(app, event)
            app.OptionsMenu.Enable = 'off';
            app.Modules.SetOpt_UI = set.SetOpt_UI(app);
        end

        % Menu selected function: ResetMenu
        function ResetMenuSelected(app, event)
            instrreset;
        end

        % Menu selected function: SetupMenu
        function SetupMenuSelected(app, event)
            app.SetupMenu.Enable = 'off';
            app.Modules.JouJSP_UI = jou.jsp.JouJSP_UI(app);
        end

        % Menu selected function: AddNoteMenu
        function AddNoteMenuSelected(app, event)
            app.AddNoteMenu.Enable = 'off';
            app.Modules.JouJAN_UI = jou.jan.JouJAN_UI(app);
        end

        % Menu selected function: BondingMenu
        function BondingMenuSelected(app, event)
            app.BondingMenu.Enable = 'off';
            app.Modules.JouBND_UI = jou.bnd.JouBND_UI(app);
        end

        % Menu selected function: MstIVsMenu
        function MstIVsMenuSelected(app, event)
            app.MstIVsMenu.Enable = 'off';
            app.Modules.MstIV_UI = mst.IV.MstIV_UI(app);
        end

        % Menu selected function: MstPulMenu
        function MstPulMenuSelected(app, event)
            app.MstPulMenu.Enable = 'off';
            app.Modules.MstPL_UI = mst.PL.MstPL_UI(app);
        end

        % Menu selected function: MstTemMenu
        function MstTemMenuSelected(app, event)
            app.MstTemMenu.Enable = 'off';
            app.Modules.MstTe_UI = mst.Te.MstTe_UI(app);
        end

        % Menu selected function: SaveSetMenu
        function SaveSetMenuSelected(app, event)
            [s,x] = uiputfile('*.mat','Save settings?');
            if s ~= 0
                    state = app.States;
                    save([x,s],'state');
            end
        end

        % Menu selected function: AnsIVsMenu
        function AnsIVsMenuSelected(app, event)
            app.AnsIVsMenu.Enable = 'off';
            app.Modules.AnsIV_UI = anl.IV.AnsIV_UI(app);
        end

        % Menu selected function: AnsPulMenu
        function AnsPulMenuSelected(app, event)
            app.AnsPulMenu.Enable = 'off';
            app.Modules.AnsPL_UI = anl.PL.AnsPL_UI(app);
        end

        % Menu selected function: LoadSetMenu
        function LoadSetMenuSelected(app, event)
            [s,x] = uigetfile('*.mat','Select the adress file');
            if s ~= 0
                state = load([x,s]);
                app.States = state.state;
            end
        end

        % Menu selected function: NtfMenu
        function NtfMenuSelected(app, event)
            %app.NtfMenu.Enable = 'off';
            %app.Modules.Ntf_UI = ntf.Ntf_UI(app);
            switch app.TlgBot.trg
                case 0
                    app.TlgBot.trg = 1;
                    app.NtfMenu.Checked = 'on';
                    fprintf('TlgBot on\n');
                case 1
                    app.TlgBot.trg = 0;
                    app.NtfMenu.Checked = 'off';
                    fprintf('TlgBot off\n');
            end
        end

        % Menu selected function: SetAVSMenu
        function SetAVSMenuSelected(app, event)
            app.SetAVSMenu.Enable = 'off';
            app.Modules.SetAVS_UI = set.SetAVS_UI(app);  
        end

        % Menu selected function: SetDAQMenu
        function SetDAQMenuSelected(app, event)
            app.SetDAQMenu.Enable = 'off';
            app.Modules.SetDAQ_UI = set.SetDAQ_UI(app);  
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UI = uifigure;
            app.UI.Name = 'MGUI 3.0';
            app.UI.CloseRequestFcn = createCallbackFcn(app, @UICloseRequest, true);
            app.DevicesMenu = uimenu(app.UI);
            app.DevicesMenu.Text = 'Devices';
            app.SetMenu = uimenu(app.DevicesMenu);
            app.SetMenu.Text = 'Set';
            app.SetDevMenu = uimenu(app.SetMenu);
            app.SetDevMenu.MenuSelectedFcn = createCallbackFcn(app, @SetDevMenuSelected, true);
            app.SetDevMenu.Text = 'Devices';
            app.SetAVSMenu = uimenu(app.SetMenu);
            app.SetAVSMenu.MenuSelectedFcn = createCallbackFcn(app, @SetAVSMenuSelected, true);
            app.SetAVSMenu.Text = 'AVS Bridge';
            app.SetDAQMenu = uimenu(app.SetMenu);
            app.SetDAQMenu.MenuSelectedFcn = createCallbackFcn(app, @SetDAQMenuSelected, true);
            app.SetDAQMenu.Text = 'NI DAQ Card';
            app.OptionsMenu = uimenu(app.DevicesMenu);
            app.OptionsMenu.MenuSelectedFcn = createCallbackFcn(app, @OptionsMenuSelected, true);
            app.OptionsMenu.Enable = 'off';
            app.OptionsMenu.Text = 'Options';
            app.ResetMenu = uimenu(app.DevicesMenu);
            app.ResetMenu.MenuSelectedFcn = createCallbackFcn(app, @ResetMenuSelected, true);
            app.ResetMenu.Separator = 'on';
            app.ResetMenu.Text = 'Reset';
            app.JournalMenu = uimenu(app.UI);
            app.JournalMenu.Text = 'Journal';
            app.SetupMenu = uimenu(app.JournalMenu);
            app.SetupMenu.MenuSelectedFcn = createCallbackFcn(app, @SetupMenuSelected, true);
            app.SetupMenu.Text = 'Setup';
            app.AddNoteMenu = uimenu(app.JournalMenu);
            app.AddNoteMenu.MenuSelectedFcn = createCallbackFcn(app, @AddNoteMenuSelected, true);
            app.AddNoteMenu.Enable = 'off';
            app.AddNoteMenu.Text = 'Add note';
            app.BondingMenu = uimenu(app.JournalMenu);
            app.BondingMenu.MenuSelectedFcn = createCallbackFcn(app, @BondingMenuSelected, true);
            app.BondingMenu.Enable = 'off';
            app.BondingMenu.Text = 'Bonding';
            app.MeasurementsMenu = uimenu(app.UI);
            app.MeasurementsMenu.Enable = 'off';
            app.MeasurementsMenu.Text = 'Measurements';
            app.MstIVsMenu = uimenu(app.MeasurementsMenu);
            app.MstIVsMenu.MenuSelectedFcn = createCallbackFcn(app, @MstIVsMenuSelected, true);
            app.MstIVsMenu.Enable = 'off';
            app.MstIVsMenu.Text = 'IV';
            app.MstPulMenu = uimenu(app.MeasurementsMenu);
            app.MstPulMenu.MenuSelectedFcn = createCallbackFcn(app, @MstPulMenuSelected, true);
            app.MstPulMenu.Enable = 'off';
            app.MstPulMenu.Text = 'Pulse';
            app.MstTemMenu = uimenu(app.MeasurementsMenu);
            app.MstTemMenu.MenuSelectedFcn = createCallbackFcn(app, @MstTemMenuSelected, true);
            app.MstTemMenu.Enable = 'off';
            app.MstTemMenu.Text = 'T';
            app.AnsMenu = uimenu(app.UI);
            app.AnsMenu.Enable = 'off';
            app.AnsMenu.Text = 'Analysis';
            app.AnsIVsMenu = uimenu(app.AnsMenu);
            app.AnsIVsMenu.MenuSelectedFcn = createCallbackFcn(app, @AnsIVsMenuSelected, true);
            app.AnsIVsMenu.Text = 'IV';
            app.AnsPulMenu = uimenu(app.AnsMenu);
            app.AnsPulMenu.MenuSelectedFcn = createCallbackFcn(app, @AnsPulMenuSelected, true);
            app.AnsPulMenu.Text = 'Pulse';
            app.AnsResMenu = uimenu(app.AnsMenu);
            app.AnsResMenu.Enable = 'off';
            app.AnsResMenu.Text = 'Resistance';
            app.MiscMenu = uimenu(app.UI);
            app.MiscMenu.Text = 'Misc';
            app.SaveSetMenu = uimenu(app.MiscMenu);
            app.SaveSetMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveSetMenuSelected, true);
            app.SaveSetMenu.Text = 'Save settings';
            app.LoadSetMenu = uimenu(app.MiscMenu);
            app.LoadSetMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadSetMenuSelected, true);
            app.LoadSetMenu.Text = 'Load settings';
            app.NtfMenu = uimenu(app.MiscMenu);
            app.NtfMenu.MenuSelectedFcn = createCallbackFcn(app, @NtfMenuSelected, true);
            app.NtfMenu.Text = 'Notifications';
            app.W_Ax = uiaxes(app.UI);
            title(app.W_Ax, 'Title')
            xlabel(app.W_Ax, 'X')
            ylabel(app.W_Ax, 'Y')
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UI)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UI)
        end
    end
end